classdef logAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        GraphTab                  matlab.ui.container.Tab
        Data3DropDown             matlab.ui.control.DropDown
        Data3DropDownLabel        matlab.ui.control.Label
        Data2DropDown             matlab.ui.control.DropDown
        Data2DropDownLabel        matlab.ui.control.Label
        Data1DropDown             matlab.ui.control.DropDown
        Data1DropDownLabel        matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes1                   matlab.ui.control.UIAxes
        SettingTab                matlab.ui.container.Tab
        timeEditField             matlab.ui.control.EditField
        velocitymmsLabel_2        matlab.ui.control.Label
        angularVelocityEditField  matlab.ui.control.EditField
        angularVelocitydegsLabel  matlab.ui.control.Label
        velocityEditField         matlab.ui.control.EditField
        velocitymmsLabel          matlab.ui.control.Label
        LogDropDown               matlab.ui.control.DropDown
        LogDropDownLabel          matlab.ui.control.Label
        TimesSlider               matlab.ui.control.Slider
        TimesLabel                matlab.ui.control.Label
        SwitchDirection           matlab.ui.control.ToggleSwitch
        kgyroKnob                 matlab.ui.control.Knob
        kgyroKnobLabel            matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        ReloadButton              matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        FolderpathLabel           matlab.ui.control.Label
        FolderButton              matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        UICourse                  matlab.ui.control.UIAxes
    end

    %プロパティ
    properties (Access = private)
        rootDir % ログフォルダのディレクトリ
        textarea = {}   % テキストエリアに表示するcell配列
        log          % ログデータのtable変数
        course      % 走行軌跡
        marker      % 現在地のプロット
        xline = cell(1,3)

        dt              % ログ記録周期
        time            % 時間配列
        velocity        % 速度配列
        angularVelocity % 角速度配列
        pulse = 60.074	% 1mmのロータリーエンコーダパルス
        kgyro = 1       % 角度補正係数
        courseSize = [  -400    800;
                        -1000   1000]
    end

    % メソッド
    methods (Access = private)

        % ログデータを取得する
        function getLogdata(app)
            filename = strsplit(app.LogDropDown.Value);
            filename = filename{1};
            app.log = readtable(append(app.rootDir,'\',filename,".csv"));      % ログファイル読み込み
%             app.time = app.log.cntlog ./ 1000;                             % 時間行列[s]
%             app.velocity = app.log.encCurrentN / app.pulse * 1000;  % 速度行列[mm/s]
%             app.angularVelocity = app.log.gyroVal_Z / 10 * -1;      % 角度速度配列[deg/s] 右旋回時にプラス

            % テキストフィールドから換算式を取得
            timeeq = split(app.timeEditField.Value);    % 時間行列[s]
            for i = 1:size(timeeq,1)
                if i == 1
                    app.time = app.log.(timeeq{1});
                elseif timeeq(i) == "*"
                    app.time = app.time * str2double(timeeq{i+1});
                elseif timeeq(i) == "/"
                    app.time = app.time / str2double(timeeq{i+1});
                end
            end

            velocityeq = split(app.velocityEditField.Value);    % 速度行列[mm/s]
            for i = 1:size(velocityeq,1)
                if i == 1
                    app.velocity = app.log.(velocityeq{1});
                elseif velocityeq(i) == "*"
                    app.velocity = app.velocity * str2double(velocityeq{i+1});
                elseif velocityeq(i) == "/"
                    app.velocity = app.velocity / str2double(velocityeq{i+1});
                end
            end

            angularVelocityeq = split(app.angularVelocityEditField.Value);  % 角度速度配列[deg/s] 右旋回時にプラス
            for i = 1:size(angularVelocityeq,1)
                if i == 1
                    app.angularVelocity = app.log.(angularVelocityeq{1});
                elseif angularVelocityeq(i) == "*"
                    app.angularVelocity = app.angularVelocity * str2double(angularVelocityeq{i+1});
                elseif angularVelocityeq(i) == "/"
                    app.angularVelocity = app.angularVelocity / str2double(angularVelocityeq{i+1});
                end
            end
            
            setTexttarea(app, {append(app.rootDir,'\',app.LogDropDown.Value,'.csv'), 'ログファイル読み込み完了'})
        end

        % ログファイル一覧の取得
        function getLogfiles(app)
            try
                origDir = pwd;                      % カレントディレクトリを保存
                cd(app.rootDir);   % ディレクトリを移動
                fileList = dir('*.csv');         % csvファイル一覧を取得
                cd(origDir);        % もとのディレクトリに移動
                fileList = struct2table(fileList);  % table型に変換
                fileList.name = convertCharsToStrings(fileList.name);    % String型に変換
                fileList.name = extract(fileList.name,digitsPattern);   % 数字を抽出
                fileList.name = str2double(fileList.name);              % double型に変換
                fileList = sortrows(fileList,'name','descend');          % 降順にソート
                fileList.name = string(fileList.name);                  % string型に変換
                app.LogDropDown.Items = {};                 % ドロップダウンメニューを初期化
                app.LogDropDown.Items = append(fileList.name,"    ",string(round(fileList.bytes/1000,0)),"KB" );          % ドロップダウンメニューに追加
    
                app.Lamp.Color = "green";   % ステータスランプ緑
                app.TextArea.Value = "";    % ステータスクリア
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                app.TextArea.Value = {ME.identifier,ME.message};   % エラー表示
                if (strcmp(ME.identifier,'MATLAB:cd:NonExistentFolder'))
                    % 指定フォルダが見つからない
                    app.FolderButton.Text = "not found folder";
                end
                if (strcmp(ME.identifier,'MATLAB:string:MustBeCharCellArrayOrString'))
                    % 指定フォルダにcsvファイルが無い
                    app.LogDropDown.Items = "not found files";
                end
            end
        end

        % ログ変数一覧を取得
        function valnames = getLogvalues(app)
            valnames = app.log.Properties.VariableNames;
            valnames = valnames(1,2:end);
            app.Data1DropDown.Items = valnames;
            app.Data2DropDown.Items = valnames;
            app.Data3DropDown.Items = valnames;
        end

        % テキストエリアに文字列を表示する
        function setTexttarea(app, str)
            app.textarea = [app.textarea str];  % cell配列に文字列を追加
            app.TextArea.Value = app.textarea;  % テキストエリアに表示
        end
        
        % コース座標の計算
        function calcCourseplot(app)
            try
                % 座標算出
                degxy = cumtrapz(app.time,  app.angularVelocity .* app.kgyro);  % 角度行列[deg/s] 角速度を積算
                x = cumtrapz(app.time, ( app.velocity .* sind(degxy) ));
                y = cumtrapz(app.time, ( app.velocity .* cosd(degxy) ));
                % timetable変数作成
                app.course = timetable(seconds(app.time),x,y,app.velocity,'VariableNames',{'x','y','velocity'});
            catch ME
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end
       
        % コース描画
        function plotCourse(app)
            try
                valnames = getLogvalues(app);  % ログ変数一覧を取得
                % プロット
                scatter(app.UICourse,app.course,'x','y','ColorVariable','velocity');
                hold(app.UICourse, 'on');
                
                
                % 条件別座標のプロット
                if max(strcmp(valnames,"modeCurve")) == 1
                    % ログにmodeCurveが存在するとき
                    TF = ischange(app.log.modeCurve);
                    scatter(app.UICourse,app.course.x(TF),app.course.y(TF),'filled','red');
                end
                hold(app.UICourse, 'off');
            catch ME
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end

        % 定数線の表示
        function setXlines(app)
            % 定数線
            delete(app.xline{1});
            delete(app.xline{2});
            delete(app.xline{3});
            app.xline{1} = xline(app.UIAxes1,app.TimesSlider.Value); %#ok<ADPROP> 
            app.xline{2} = xline(app.UIAxes2,app.TimesSlider.Value); %#ok<ADPROP> 
            app.xline{3} = xline(app.UIAxes3,app.TimesSlider.Value); %#ok<ADPROP> 
        end
        
        % ログの取得、再プロット
        function reload(app,EventName)
            if max(strcmp(EventName,"ButtonPushed")) == 1
                app.rootDir = app.FolderButton.Text; % ログフォルダのディレクトリを取得
                getLogfiles(app);   % ログファイル一覧を取得
            end
            getLogdata(app);    % ログデータを取得する
            getLogvalues(app);  % ログ変数一覧を取得

            % プロット初期化
            cla(app.UICourse)
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            cla(app.UIAxes3)

            % 変数プロットのx軸設定
            app.UIAxes1.XLim = [0 app.time(end,1)];
            app.UIAxes2.XLim = [0 app.time(end,1)];
            app.UIAxes3.XLim = [0 app.time(end,1)];

            % スライダー範囲の設定
            app.TimesSlider.Limits = [app.time(1,1) app.time(end,1)];
            app.TimesSlider.Value = app.time(1,1);      % スライダー位置初期化
            

            % 変数初期化
            app.kgyro = 1;
            app.kgyroKnob.Value = 1;
            app.Spinner.Value = 1;

            % コース座標計算
            calcCourseplot(app);    % コース座標計算
            
            % コース描画
            plotCourse(app)

            % 変数プロット
            value = app.Data1DropDown.Value;
            plot(app.UIAxes1,app.time,app.log.(value))
            setXlines(app);

            value = app.Data2DropDown.Value;
            plot(app.UIAxes2,app.time,app.log.(value))
            setXlines(app);

            value = app.Data3DropDown.Value;
            plot(app.UIAxes3,app.time,app.log.(value))
            setXlines(app);

%             save()
        end
        
        % 設定用変数の保存
        function saveVars(app)
            folderDir = app.rootDir;
            equations = {app.timeEditField.Value app.velocityEditField.Value app.angularVelocityEditField.Value};

            save("settingVars","folderDir","equations");
            setTexttarea(app, "設定保存完了")
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 起動時
            try
                % 設定用変数ファイルの読み込み
                % 換算式
                load("settingvars.mat")
                app.timeEditField.Value = equations{1};
                app.velocityEditField.Value = equations{2};
                app.angularVelocityEditField.Value = equations{3};

                % フォルダパス
                app.rootDir = folderDir;
                app.FolderButton.Text = app.rootDir;
            catch ME
                if (strcmp(ME.identifier,'MATLAB:load:couldNotReadFile'))
                    % 設定用変数ファイルが無い
                end
            end

            app.rootDir = app.FolderButton.Text; % ログフォルダのディレクトリを取得
            getLogfiles(app);   % ログファイル一覧を取得
            getLogdata(app);    % ログデータを取得する
            getLogvalues(app);  % ログ変数一覧を取得

            % スライダー範囲の設定
            app.TimesSlider.Limits = [app.time(1,1) app.time(end,1)];
            % 変数プロットのx軸設定
            app.UIAxes1.XLim = [0 app.time(end,1)];
            app.UIAxes2.XLim = [0 app.time(end,1)];
            app.UIAxes3.XLim = [0 app.time(end,1)];

            % コース軸の設定
            c = colorbar(app.UICourse);         % カラーバー表示
            c.Label.String = 'velocity[mm/s]';
            c.Label.FontSize = 12;
            
            % 軸設定
            if strcmp(app.SwitchDirection.Value,'時計回り') == 1
                app.UICourse.XLim = [app.courseSize(1,1) app.courseSize(1,2)];
                app.UICourse.YLim = [app.courseSize(2,1) app.courseSize(2,2)];
            else
                app.UICourse.XLim = [-app.courseSize(1,1) -app.courseSize(1,2)];
                app.UICourse.YLim = [-app.courseSize(2,1) -app.courseSize(2,2)];
            end
            app.UICourse.XTick = -10000:100:10000;
            app.UICourse.YTick = -10000:100:10000;

            % コース座標計算
            calcCourseplot(app);    % コース座標計算
            
            % コース描画
            plotCourse(app)

            % 変数プロット
            app.Data1DropDown.Value = "encCurrentN";
            app.Data2DropDown.Value = "gyroVal_Z";
            app.Data3DropDown.Value = "angleSensor";
            title(app.UIAxes1, app.Data1DropDown.Value)
            title(app.UIAxes2, app.Data2DropDown.Value)
            title(app.UIAxes3, app.Data3DropDown.Value)

            value = app.Data1DropDown.Value;
            plot(app.UIAxes1,app.time,app.log.(value))
            setXlines(app);

            value = app.Data2DropDown.Value;
            plot(app.UIAxes2,app.time,app.log.(value))
            setXlines(app);

            value = app.Data3DropDown.Value;
            plot(app.UIAxes3,app.time,app.log.(value))
            setXlines(app);
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            % フォルダー選択
            app.rootDir = uigetdir;
            if app.rootDir ~= 0
                app.FolderButton.Text = app.rootDir;
                getLogfiles(app);       % ログファイル一覧を取得
            end
        end

        % Value changed function: LogDropDown
        function LogDropDownValueChanged(app, event)
            reload(app,event.EventName);
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            reload(app,event.EventName);
            saveVars(app);
        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            changingValue = event.Value;

            t = round(changingValue,2) + (app.time(1,1)); % 数値補正 小数点3桁目を揃える
            if t > app.TimesSlider.Limits(2)
                t = app.TimesSlider.Limits(2);
            end
            app.TimesSlider.Value = t;      % スライダー位置の補正
            t = seconds(t);                 % duration 配列に変換

            delete(app.marker)          % 前回値を削除
            hold(app.UICourse, 'on');
            app.marker = scatter(app.UICourse,app.course{t,'x'},app.course{t,'y'},"m",'filled');
            hold(app.UICourse, 'off');
            setXlines(app);
            
        end

        % Value changed function: Data1DropDown
        function Data1DropDownValueChanged(app, event)
            value = app.Data1DropDown.Value;
            
            plot(app.UIAxes1,app.time,app.log.(value))
            setXlines(app);
            title(app.UIAxes1, value)
        end

        % Value changed function: Data2DropDown
        function Data2DropDownValueChanged(app, event)
            value = app.Data2DropDown.Value;
            
            plot(app.UIAxes2,app.time,app.log.(value))
            setXlines(app);
            title(app.UIAxes2, value)
        end

        % Value changed function: Data3DropDown
        function Data3DropDownValueChanged(app, event)
            value = app.Data3DropDown.Value;
            
            plot(app.UIAxes3,app.time,app.log.(value))
            setXlines(app);
            title(app.UIAxes3, value)
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.kgyroKnob.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            calcCourseplot(app);    % コース座標計算
            % コース描画
            plotCourse(app)
        end

        % Value changing function: kgyroKnob
        function kgyroKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            calcCourseplot(app);    % コース座標計算
            % コース描画
            plotCourse(app)
        end

        % Value changed function: SwitchDirection
        function SwitchDirectionValueChanged(app, event)
            if strcmp(app.SwitchDirection.Value,'時計回り') == 1
                app.UICourse.XLim = [app.courseSize(1,1) app.courseSize(1,2)];
                app.UICourse.YLim = [app.courseSize(2,1) app.courseSize(2,2)];
            else
                app.UICourse.XLim = [-app.courseSize(1,2) -app.courseSize(1,1)];
                app.UICourse.YLim = [-app.courseSize(2,2) -app.courseSize(2,1)];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveVars(app); % 変数保存
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UICourse = uiaxes(app.UIFigure);
            xlabel(app.UICourse, 'X[mm]')
            ylabel(app.UICourse, 'Y[mm]')
            zlabel(app.UICourse, 'Z[mm]')
            app.UICourse.XLim = [0 1];
            app.UICourse.YLim = [0 1];
            app.Lamp = uilamp(app.UIFigure);
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'H:';
            app.FolderpathLabel = uilabel(app.UIFigure);
            app.FolderpathLabel.Text = 'Folder path';
            app.TextArea = uitextarea(app.UIFigure);
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Step = 0.001;
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [0.9 1.1];
            app.Spinner.Value = 1;
            app.kgyroKnobLabel = uilabel(app.UIFigure);
            app.kgyroKnobLabel.Text = 'kgyro';
            app.kgyroKnob = uiknob(app.UIFigure, 'continuous');
            app.kgyroKnob.Limits = [0.9 1.1];
            app.kgyroKnob.ValueChangingFcn = createCallbackFcn(app, @kgyroKnobValueChanging, true);
            app.kgyroKnob.Value = 1;
            app.SwitchDirection = uiswitch(app.UIFigure, 'toggle');
            app.SwitchDirection.Items = {'反時計回り', '時計回り'};
            app.SwitchDirection.ValueChangedFcn = createCallbackFcn(app, @SwitchDirectionValueChanged, true);
            app.SwitchDirection.Value = '時計回り';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time [s]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            app.LogDropDownLabel = uilabel(app.UIFigure);
            app.LogDropDownLabel.Text = 'Log';
            app.LogDropDown = uidropdown(app.UIFigure);
            app.LogDropDown.Items = {'option'};
            app.LogDropDown.ValueChangedFcn = createCallbackFcn(app, @LogDropDownValueChanged, true);
            app.LogDropDown.Value = 'option';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes1 = uiaxes(app.GraphTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'Time [s]')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GraphTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraphTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Data1DropDownLabel = uilabel(app.GraphTab);
            app.Data1DropDownLabel.Text = 'Data1';
            app.Data1DropDown = uidropdown(app.GraphTab);
            app.Data1DropDown.ValueChangedFcn = createCallbackFcn(app, @Data1DropDownValueChanged, true);
            app.Data2DropDownLabel = uilabel(app.GraphTab);
            app.Data2DropDownLabel.Text = 'Data2';
            app.Data2DropDown = uidropdown(app.GraphTab);
            app.Data2DropDown.ValueChangedFcn = createCallbackFcn(app, @Data2DropDownValueChanged, true);
            app.Data3DropDownLabel = uilabel(app.GraphTab);
            app.Data3DropDownLabel.Text = 'Data3';
            app.Data3DropDown = uidropdown(app.GraphTab);
            app.Data3DropDown.ValueChangedFcn = createCallbackFcn(app, @Data3DropDownValueChanged, true);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.velocitymmsLabel = uilabel(app.SettingTab);
            app.velocitymmsLabel.Text = 'velocity [mm/s] = ';
            app.velocityEditField = uieditfield(app.SettingTab, 'text');
            app.velocityEditField.Value = 'encCurrentN / 60.074 * 1000';
            app.angularVelocitydegsLabel = uilabel(app.SettingTab);
            app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
            app.angularVelocityEditField = uieditfield(app.SettingTab, 'text');
            app.angularVelocityEditField.Value = 'gyroVal_Z / 10 * -1';
            app.velocitymmsLabel_2 = uilabel(app.SettingTab);
            app.velocitymmsLabel_2.Text = 'time [s] = ';
            app.timeEditField = uieditfield(app.SettingTab, 'text');
            app.timeEditField.Value = 'cntlog / 1000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end