classdef logAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button_Expand             matlab.ui.control.Button
        Button_Rotate             matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        GraphsTab                 matlab.ui.container.Tab
        Data5DropDown             matlab.ui.control.DropDown
        Data5DropDownLabel        matlab.ui.control.Label
        Data4DropDown             matlab.ui.control.DropDown
        Data4DropDownLabel        matlab.ui.control.Label
        Data1DropDown             matlab.ui.control.DropDown
        Data1DropDownLabel        matlab.ui.control.Label
        Data3DropDown             matlab.ui.control.DropDown
        Data3DropDownLabel        matlab.ui.control.Label
        Data2DropDown             matlab.ui.control.DropDown
        Data2DropDownLabel        matlab.ui.control.Label
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes1                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        AnalysisTab               matlab.ui.container.Tab
        ylimmatchSwitch           matlab.ui.control.Switch
        ylimmatchSwitchLabel      matlab.ui.control.Label
        detrendRSwitch            matlab.ui.control.Switch
        detrendSwitch_2Label      matlab.ui.control.Label
        normalizeRSwitch          matlab.ui.control.Switch
        normalizeSwitch_2Label    matlab.ui.control.Label
        detrendLSwitch            matlab.ui.control.Switch
        detrendSwitchLabel        matlab.ui.control.Label
        normalizeLSwitch          matlab.ui.control.Switch
        normalizeSwitchLabel      matlab.ui.control.Label
        DataAnalysisLDropDown     matlab.ui.control.DropDown
        smoothLDropDown           matlab.ui.control.DropDown
        FunctionLabel             matlab.ui.control.Label
        windowLSpinner            matlab.ui.control.Spinner
        windowSpinnerLabel        matlab.ui.control.Label
        DataAnalysisRDropDown     matlab.ui.control.DropDown
        smoothRDropDown           matlab.ui.control.DropDown
        windowRSpinner            matlab.ui.control.Spinner
        windowSpinner_2Label      matlab.ui.control.Label
        smoothDropDown_2Label     matlab.ui.control.Label
        DataDropDownLabel_2       matlab.ui.control.Label
        DataLabel                 matlab.ui.control.Label
        UIAxesAnalysis            matlab.ui.control.UIAxes
        ParameterTab              matlab.ui.container.Tab
        TextArea_2                matlab.ui.control.TextArea
        consoleTab                matlab.ui.container.Tab
        TextArea                  matlab.ui.control.TextArea
        SettingTab                matlab.ui.container.Tab
        Plotflag3DropDown         matlab.ui.control.DropDown
        Plotflag3DropDownLabel    matlab.ui.control.Label
        Plotflag3enableCheckBox   matlab.ui.control.CheckBox
        Plotflag2DropDown         matlab.ui.control.DropDown
        Plotflag2DropDownLabel    matlab.ui.control.Label
        Plotflag2enableCheckBox   matlab.ui.control.CheckBox
        Plotflag1DropDown         matlab.ui.control.DropDown
        PlotflagLabel             matlab.ui.control.Label
        Plotflag1enableCheckBox   matlab.ui.control.CheckBox
        diffCheckBox              matlab.ui.control.CheckBox
        ShortCutwindowLabel       matlab.ui.control.Label
        ShortCutwindowSlider      matlab.ui.control.Slider
        ShortCutplotSwitch        matlab.ui.control.Switch
        ShortCutplotSwitchLabel   matlab.ui.control.Label
        SettingCSVfilepathLabel   matlab.ui.control.Label
        csvFilePathButton         matlab.ui.control.Button
        csvFilePathEditer         matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        SwitchDirection           matlab.ui.control.ToggleSwitch
        CourseajustgyroKnob       matlab.ui.control.Knob
        CourseajustgyroKnobLabel  matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        graphPositionBotton       matlab.ui.control.Switch
        SwitchLabel               matlab.ui.control.Label
        radsCheckBox              matlab.ui.control.CheckBox
        TranslateequationsLabel   matlab.ui.control.Label
        timeEditField             matlab.ui.control.EditField
        velocitymmsLabel_2        matlab.ui.control.Label
        angularVelocityEditField  matlab.ui.control.EditField
        angularVelocitydegsLabel  matlab.ui.control.Label
        velocityEditField         matlab.ui.control.EditField
        velocitymmsLabel          matlab.ui.control.Label
        LogDropDown               matlab.ui.control.DropDown
        LogDropDownLabel          matlab.ui.control.Label
        TimesSlider               matlab.ui.control.Slider
        TimesLabel                matlab.ui.control.Label
        ReloadButton              matlab.ui.control.Button
        FolderpathLabel           matlab.ui.control.Label
        FolderButton              matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        LineSensorAxes            matlab.ui.control.UIAxes
        UIAxisCourse              matlab.ui.control.UIAxes
    end

    %プロパティ
    properties (Access = private)
        rootDir % ログフォルダのディレクトリ
        csvFileName = "LogViewerSetting.csv"
        textarea = {}       % テキストエリアに表示する文字列用cell配列
        log                 % ログデータ用table変数
        course              % 走行軌跡の座標配列
        shortCutCourse      % ショートカットの走行軌跡座標配列
        marker              % 現在地のプロット
        flag1               % フラグをコースにプロット
        flag2               % フラグをコースにプロット
        flag3               % フラグをコースにプロット
        xlines = cell(1,6) % X軸定数線のオブジェクト配列
        courseColorbar      % コース描画用カラーバーハンドル
        lineSensorImage     % 5行×10センサーの濃淡画像
        lineSensorSelection % 選択中のログ行を示す枠
        coursePlot          % 基準軌跡の散布図（座標・色を更新して再利用）
        shortCutPlot        % ショートカット軌跡の散布図
        variablePlots = cell(1,5) % 変数グラフの線
        analysisPlots = cell(1,2) % 左右の解析グラフの線
        expand              % コースプロット最大化フラグ 1:最大
        rotate = 0          % コース全体の回転角度[deg]（rad入力時も度で保持）

        time            % 時間配列
        dt              % 最初の単位時間
        velocity        % 速度配列
        distance        % 速度換算式から取得または積分した累積距離[mm]
        distanceFromEquation = false % 読込時にdiffが有効なら換算式を累積距離として使用
        angularVelocity % 角速度配列
        pulse = 58.019	% 1mmのロータリーエンコーダパルス
        kgyro = 1       % 角度補正係数
        courseSize = [  -400    800;
                        -1000   1000]
        parameter          % ログのヘッダー
    end

    % メソッド
    methods (Access = private)

        % ログデータを取得する
        function isSuccess = getLogdata(app)
            isSuccess = false; % 全処理が成功するまで未完了とする

			try
				% ログファイル読み込みと最低限の行数チェックを例外処理で保護
                if isempty(app.LogDropDown.ItemsData)
                    error("logAnalysis:NoLogFiles","読み込み可能なログファイルがありません。");
                end
                filePath = fullfile(app.rootDir,app.LogDropDown.Value);
                % 先頭2行だけを読み、メタ情報は最初から文字列として扱う
                [parameterData,columnNames] = readLogHeaders(app,filePath);
                importOptions = detectImportOptions(filePath, ...
                    'VariableNamesLine',2,'VariableNamingRule','preserve');
                importOptions.DataLines = [3 Inf];
                if ~all(ismember(columnNames,string(importOptions.VariableNames)))
                    error("logAnalysis:InvalidHeader","列名行と読込オプションの列名が一致しません。");
                end
                % 末尾カンマによる空列を本体テーブルへ取り込まない
                importOptions.SelectedVariableNames = cellstr(columnNames);
                logData = readtable(filePath,importOptions);
                if height(logData) < 2
                    error("logAnalysis:InsufficientLogRows","ログファイルの行数が2行未満です。");
                end

                % 時間[s]は有限・非負で、各ログ行で必ず増加すること
                timeData = evaluateLogEquation(app,logData,app.timeEditField.Value,"時間");
                intervals = diff(timeData);
                if any(timeData < 0) || any(~isfinite(intervals)) || any(intervals <= 0)
                    error("logAnalysis:InvalidTime", ...
                        "時間は非負かつ単調増加が必要です。重複・逆行・不正な時間間隔を確認してください。");
                end
                dtData = [intervals;intervals(end)];

                % 速度[mm/s]。diff時は換算後の値を時間間隔で微分する
                velocityData = evaluateLogEquation(app,logData,app.velocityEditField.Value,"速度");
                distanceFromEquationData = app.diffCheckBox.Value;
                if distanceFromEquationData
                    distanceData = velocityData; % 微分前の累積距離[mm]を軌跡に使う
                    delta = diff(velocityData);
                    velocityData = [delta;delta(end)] ./ dtData;
                    if any(~isfinite(velocityData))
                        error("logAnalysis:InvalidVelocity","速度の差分計算結果にInfまたはNaNがあります。");
                    end
                else
                    % 速度[mm/s]の入力は、ログ時間[s]で台形積分する。
                    distanceData = cumtrapz(timeData,velocityData);
                end
                if any(~isfinite(distanceData)) || any(~isfinite(diff([0;distanceData])))
                    error("logAnalysis:InvalidDistance","速度換算式から求めた距離にInfまたはNaNがあります。");
                end

                % 角速度は単位チェックボックスで指定したdeg/sまたはrad/s
                angularVelocityData = evaluateLogEquation(app,logData,app.angularVelocityEditField.Value,"角速度");
                if isempty(parameterData)
                    app.TextArea_2.Value = "";
                else
                    app.TextArea_2.Value = parameterData;
                end
                % 全ての読み込み・換算が成功した後にまとめて反映する
                app.log = logData;
                app.time = timeData;
                app.dt = dtData;
                app.velocity = velocityData;
                app.distance = distanceData;
                app.distanceFromEquation = distanceFromEquationData;
                app.angularVelocity = angularVelocityData;
                app.parameter = parameterData;
                isSuccess = true;

			catch ME
				app.Lamp.Color = "red";	% 例外発生をランプで通知
				setTexttarea(app, {ME.identifier, ME.message});	% エラー内容をテキストエリアに表示
				isSuccess = false;
			end

			% 例外時は後続処理をスキップ
			if ~isSuccess
				return;
			end

			setTexttarea(app, {filePath, 'ログファイル読み込み完了'});
        end
        

        % UTF-8ログの先頭2行を読み、メタ情報と列名を文字列へ統一する
        % 引数 filePath: ログCSVのパス
        % 戻り値 parameters: 表示用の文字列列ベクトル、columnNames: 列名
        function [parameters,columnNames] = readLogHeaders(app,filePath)
            [fileId,message] = fopen(filePath,'r','n','UTF-8');
            if fileId < 0
                error("logAnalysis:HeaderReadFailed","ヘッダを読み込めません: %s",message);
            end
            cleanupFile = onCleanup(@()fclose(fileId));
            metadataLine = fgetl(fileId);
            columnsLine = fgetl(fileId);
            if ~ischar(metadataLine) || ~ischar(columnsLine)
                error("logAnalysis:InvalidHeader","ログにはメタ情報行と列名行が必要です。");
            end
            if ~isempty(metadataLine) && metadataLine(1)==char(65279)
                metadataLine = metadataLine(2:end); % UTF-8 BOMを除く
            end
            % %qで引用符内のカンマも扱い、数値・NaNを数値へ変換しない
            parameters = strings(0,1);
            if ~isempty(metadataLine)
                metadata = textscan(metadataLine,'%q','Delimiter',',','Whitespace','');
                parameters = string(metadata{1});
                parameters(ismissing(parameters)) = "";
                parameters = parameters(strlength(parameters)>0);
                parameters = parameters(:);
            end

            if isempty(columnsLine)
                error("logAnalysis:InvalidHeader","列名行が空です。");
            end
            columns = textscan(columnsLine,'%q','Delimiter',',','Whitespace','');
            columnNames = strtrim(string(columns{1}));
            while ~isempty(columnNames) && strlength(columnNames(end))==0
                columnNames(end) = []; % ログの末尾カンマを許容する
            end
            if isempty(columnNames) || any(ismissing(columnNames)) || ...
                    any(strlength(columnNames)==0) || numel(unique(columnNames))~=numel(columnNames)
                error("logAnalysis:InvalidHeader","列名が空、または重複しています。");
            end
            columnNames = columnNames(:);
        end
        % ログ列・数値・四則演算・括弧からなる換算式を行ごとに計算する
        % 引数 logData: 読込ログ、equation: 換算式、label: エラー表示用の項目名
        % 戻り値 data: 有限な実数の列ベクトル（double）
        function data = evaluateLogEquation(app,logData,equation,label)
            postfix = validateLogEquation(app,equation,label);
            stack = cell(1,numel(postfix));
            count = 0;
            for tokenIndex = 1:numel(postfix)
                token = postfix{tokenIndex};
                if ismember(token,{'u+','u-'})
                    if strcmp(token,'u-')
                        stack{count} = -stack{count};
                    end
                elseif ismember(token,{'+','-','*','/'})
                    right = stack{count};
                    left = stack{count-1};
                    count = count-1;
                    switch token
                        case '+'
                            stack{count} = left + right;
                        case '-'
                            stack{count} = left - right;
                        case '*'
                            stack{count} = left .* right;
                        case '/'
                            if any(right(:) == 0)
                                error("logAnalysis:InvalidEquation","%s換算式でゼロ除算はできません。",label);
                            end
                            stack{count} = left ./ right;
                    end
                else
                    count = count+1;
                    if ~isempty(regexp(token,'^[A-Za-z]\w*$','once'))
                        if ~ismember(token,logData.Properties.VariableNames)
                            error("logAnalysis:MissingColumn","%s換算式の列がありません: %s",label,token);
                        end
                        value = logData.(token);
                        if ~isnumeric(value) || ~isreal(value) || ~isvector(value) || ...
                                numel(value) ~= height(logData) || any(~isfinite(value(:)))
                            error("logAnalysis:InvalidColumn", ...
                                "%s換算式の列は、各行に有限な実数が必要です: %s",label,token);
                        end
                        % 整数型の列も加算前にdoubleへ変換し、飽和と0.5パルス欠落を避ける。
                        stack{count} = double(value(:));
                    else
                        stack{count} = str2double(token);
                    end
                end
                if any(~isfinite(stack{count}(:)))
                    error("logAnalysis:InvalidEquation","%s換算結果にInfまたはNaNがあります。",label);
                end
            end
            data = stack{1};
            if isscalar(data)
                data = repmat(data,height(logData),1);
            end
        end

        % 換算式を検証し、演算順序を確定した後置記法へ変換する
        % 引数 equation: 換算式、label: エラー表示用の項目名
        % 戻り値 postfix: 列名・数値・演算子のトークン（設定検証でも使用）
        function postfix = validateLogEquation(app,equation,label)
            equation = char(string(equation));
            if isempty(strtrim(equation)) || numel(equation) > 4096
                error("logAnalysis:InvalidEquation","%s換算式が空、または長すぎます。",label);
            end
            % 符号は演算子として扱う。指数の符号だけは数値の一部とする。
            pattern = '[A-Za-z]\w*|(?:\d+(?:\.\d*)?|\.\d+)(?:[eE][+-]?\d+)?|[+*/()\-]';
            [starts,ends,tokens] = regexp(equation,pattern,'start','end','match');
            remainder = equation;
            for tokenIndex = 1:numel(starts)
                remainder(starts(tokenIndex):ends(tokenIndex)) = ' ';
            end
            if ~isempty(strtrim(remainder)) || isempty(tokens) || numel(tokens) > 1024
                error("logAnalysis:InvalidEquation", ...
                    "%s換算式には列名・数値・四則演算・括弧を指定してください。",label);
            end
            [postfix,index] = parseLogExpression(app,tokens,1,1,label,0);
            if index <= numel(tokens)
                error("logAnalysis:InvalidEquation","%s換算式の演算子または括弧が不正です。",label);
            end
        end

        % 優先順位に従って式を解析し、定数部分の不正値も検出する
        % 引数 tokens: 入力、index: 開始位置、minimum: 最低優先順位、label: 項目名、depth: 再帰深さ
        % 戻り値 postfix: 後置記法、index: 次の位置、constant: 定数値（列を含む場合NaN）
        function [postfix,index,constant] = parseLogExpression(app,tokens,index,minimum,label,depth)
            if index > numel(tokens) || depth > 64
                error("logAnalysis:InvalidEquation","%s換算式の値が不足、または括弧・符号が深すぎます。",label);
            end
            token = tokens{index};
            index = index+1;
            if ismember(token,{'+','-'})
                [postfix,index,constant] = parseLogExpression(app,tokens,index,3,label,depth+1);
                postfix{end+1} = ['u' token];
                if strcmp(token,'-'), constant = -constant; end
            elseif strcmp(token,'(')
                [postfix,index,constant] = parseLogExpression(app,tokens,index,1,label,depth+1);
                if index > numel(tokens) || ~strcmp(tokens{index},')')
                    error("logAnalysis:InvalidEquation","%s換算式の閉じ括弧がありません。",label);
                end
                index = index+1;
            elseif ~isempty(regexp(token,'^[A-Za-z]\w*$','once'))
                postfix = {token};
                constant = NaN;
            else
                constant = str2double(token);
                if ~isfinite(constant)
                    error("logAnalysis:InvalidEquation","%s換算式には有限な実数を指定してください: %s",label,token);
                end
                postfix = {token};
            end
            while index <= numel(tokens)
                operator = tokens{index};
                if ismember(operator,{'+','-'})
                    precedence = 1;
                elseif ismember(operator,{'*','/'})
                    precedence = 2;
                else
                    break;
                end
                if precedence < minimum, break; end
                [right,index,rightConstant] = parseLogExpression(app,tokens,index+1,precedence+1,label,depth+1);
                postfix = [postfix right {operator}]; %#ok<AGROW>
                if strcmp(operator,'/') && rightConstant == 0
                    error("logAnalysis:InvalidEquation","%s換算式でゼロ除算はできません。",label);
                end
                if isfinite(constant) && isfinite(rightConstant)
                    switch operator
                        case '+', constant = constant + rightConstant;
                        case '-', constant = constant - rightConstant;
                        case '*', constant = constant * rightConstant;
                        case '/', constant = constant / rightConstant;
                    end
                    if ~isfinite(constant)
                        error("logAnalysis:InvalidEquation","%s換算結果にInfまたはNaNがあります。",label);
                    end
                else
                    constant = NaN;
                end
            end
        end


        % 設定CSVを全項目検証し、検証成功後にUIへ反映する
        % 引数 filePath: 設定CSVのパス
        % 戻り値 isSuccess: 読込・反映成功、settingsMap: 検証済み設定
        function [isSuccess,settingsMap] = loadSettings(app,filePath)
            isSuccess = false;
            settingsMap = [];
            try
                settingsTable = readtable(filePath,'TextType','string');
                if ~all(ismember({'key','value'},settingsTable.Properties.VariableNames))
                    error("logAnalysis:InvalidSettings","設定CSVにはkey,value列が必要です。");
                end
                keys = strtrim(string(settingsTable.key));
                values = strtrim(string(settingsTable.value));
                if any(ismissing(keys)) || any(ismissing(values)) || ...
                        any(strlength(keys)==0) || any(strlength(values)==0) || ...
                        numel(unique(keys)) ~= numel(keys)
                    error("logAnalysis:InvalidSettings","設定CSVに空の項目または重複キーがあります。");
                end
                candidate = containers.Map(cellstr(keys),cellstr(values));
                required = {'logFolderDir','timeEquation','velocityEquation','angularVelocityEquation', ...
                    'radsCheckBox','switchDirection','data1','data2','data3','analysisLeft','analysisRight', ...
                    'smoothLeft','smoothRight','windowLeft','windowRight','normalizeLeft','normalizeRight', ...
                    'detrendLeft','detrendRight','ylimMatch','plotSwitch','windowSlider'};
                missingKeys = required(~isKey(candidate,required));
                if ~isempty(missingKeys)
                    error("logAnalysis:InvalidSettings","設定キーが不足しています: %s",strjoin(missingKeys,', '));
                end
                % 旧設定にないグラフ項目とdiff状態は既定値を補って読み込む
                extraKeys = {'data4','data5','diffCheckBox'};
                extraDefaults = {'gyroVal_Z','encCurrentCorr_p','false'};
                for itemIndex = 1:numel(extraKeys)
                    if ~isKey(candidate,extraKeys{itemIndex})
                        candidate(extraKeys{itemIndex}) = extraDefaults{itemIndex};
                    end
                end
                if ~isfolder(candidate('logFolderDir'))
                    error("logAnalysis:InvalidSettings","ログフォルダが存在しません: %s",candidate('logFolderDir'));
                end
                validateLogEquation(app,candidate('timeEquation'),"時間");
                validateLogEquation(app,candidate('velocityEquation'),"速度");
                validateLogEquation(app,candidate('angularVelocityEquation'),"角速度");
                if ~ismember(lower(candidate('radsCheckBox')),{'true','false'})
                    error("logAnalysis:InvalidSettings","radsCheckBoxはtrueまたはfalseで指定してください。");
                end
                if ~ismember(lower(candidate('diffCheckBox')),{'true','false'})
                    error("logAnalysis:InvalidSettings","diffCheckBoxはtrueまたはfalseで指定してください。");
                end
                optionKeys = {'switchDirection','smoothLeft','smoothRight','normalizeLeft', ...
                    'normalizeRight','detrendLeft','detrendRight','ylimMatch','plotSwitch'};
                optionControls = {app.SwitchDirection,app.smoothLDropDown,app.smoothRDropDown, ...
                    app.normalizeLSwitch,app.normalizeRSwitch,app.detrendLSwitch,app.detrendRSwitch, ...
                    app.ylimmatchSwitch,app.ShortCutplotSwitch};
                for itemIndex = 1:numel(optionKeys)
                    key = optionKeys{itemIndex};
                    if ~ismember(candidate(key),optionControls{itemIndex}.Items)
                        error("logAnalysis:InvalidSettings","%sの設定値が不正です: %s",key,candidate(key));
                    end
                end
                numberKeys = {'windowLeft','windowRight','windowSlider'};
                numberControls = {app.windowLSpinner,app.windowRSpinner,app.ShortCutwindowSlider};
                numberValues = zeros(1,3);
                for itemIndex = 1:3
                    key = numberKeys{itemIndex};
                    value = str2double(candidate(key));
                    limits = numberControls{itemIndex}.Limits;
                    if ~isreal(value) || ~isfinite(value) || value ~= fix(value) || ...
                            value < limits(1) || value > limits(2)
                        error("logAnalysis:InvalidSettings","%sは範囲内の整数で指定してください。",key);
                    end
                    numberValues(itemIndex) = value;
                end

                % 以下はすべて検証済み。途中までの設定反映を避ける
                app.timeEditField.Value = candidate('timeEquation');
                app.velocityEditField.Value = candidate('velocityEquation');
                app.angularVelocityEditField.Value = candidate('angularVelocityEquation');
                app.radsCheckBox.Value = strcmpi(candidate('radsCheckBox'),'true');
                app.diffCheckBox.Value = strcmpi(candidate('diffCheckBox'),'true');
                for itemIndex = 1:numel(optionKeys)
                    optionControls{itemIndex}.Value = candidate(optionKeys{itemIndex});
                end
                for itemIndex = 1:3
                    numberControls{itemIndex}.Value = numberValues(itemIndex);
                end
                app.rootDir = string(candidate('logFolderDir'));
                [~,folderName] = fileparts(char(app.rootDir));
                app.FolderButton.Text = folderName;
                app.csvFilePathEditer.Value = fileparts(char(filePath));
                settingsMap = candidate;
                isSuccess = true;
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"設定読込失敗",ME.identifier,ME.message});
            end
        end

        % ログ列の一覧に存在する保存済み選択だけを復元する
        % 引数 settingsMap: 検証済み設定
        function restoreLogSelections(app,settingsMap)
            keys = {'data1','data2','data3','data4','data5','analysisLeft','analysisRight'};
            controls = {app.Data1DropDown,app.Data2DropDown,app.Data3DropDown, ...
                app.Data4DropDown,app.Data5DropDown,app.DataAnalysisLDropDown,app.DataAnalysisRDropDown};
            for itemIndex = 1:numel(keys)
                key = keys{itemIndex};
                value = settingsMap(key);
                if ismember(value,controls{itemIndex}.Items)
                    controls{itemIndex}.Value = value;
                else
                    setTexttarea(app,{sprintf("設定項目%sの列%sがログにないため、現在の選択を使用します。",key,value)});
                end
            end
        end

        % 保存途中の一時ファイルを、残っている場合だけ削除する
        % 引数 filePath: この保存処理が作成した一時ファイル
        function cleanupSettingsFile(app,filePath)
            if isfile(filePath)
                delete(filePath);
            end
        end
        % 一覧用に作成日時と先頭メタ情報だけを取得する。
        % 引数 filePath: CSVパス、logNumber: ログ番号、fileSize: サイズ[kB]の文字列。
        % 戻り値 label: ログ番号・作成日時[月/日/時:分]・走行モード・停止番号・サイズ。
        function label = getLogListLabel(app,filePath,logNumber,fileSize)
            dateLabel = "?";
            modeLabel = "?";
            stopLabel = "?";
            try
                % dirの日時ではなく、Windowsのファイル作成日時（ローカル時刻）を使う。
                created = System.IO.File.GetCreationTime(char(filePath));
                dateLabel = string(char(created.ToString('MM/dd/HH:mm')));
            catch
                % 属性を取得できないファイルも一覧には残す。
            end
            try
                [parameters,~] = readLogHeaders(app,filePath);
                mode = upper(logMetadataValue(app,parameters,"optimalTrace"));
                modes = ["PRIMARY","DISTANCE","SLIP","SHORTCUT","PATH","PATH_CURVE","SHORTCUT_CURVE"];
                if ismember(mode,modes)
                    modeLabel = mode;
                else
                    % 旧数値形式も表示する。内部番号2のSLIPは要求方式で区別する。
                    number = str2double(mode);
                    numbers = [0 2 3 4 5 6];
                    legacyModes = ["PRIMARY","DISTANCE","SHORTCUT","PATH","PATH_CURVE","SHORTCUT_CURVE"];
                    index = find(numbers==number,1);
                    if ~isempty(index)
                        modeLabel = legacyModes(index);
                        requested = upper(logMetadataValue(app,parameters,"requestedMode"));
                        if number==2 && requested=="SLIP"
                            modeLabel = "SLIP";
                        end
                    end
                end
                stop = str2double(logMetadataValue(app,parameters,"emcStop"));
                if isfinite(stop) && stop>=0 && stop==fix(stop)
                    stopLabel = string(sprintf('%.0f',stop));
                end
            catch
                % 不正・読込不可のヘッダは不明表示とし、他ログの一覧取得を継続する。
            end
            % 等幅フォントと固定文字幅で、タブの描画に依存せず各列を揃える。
            label = string(sprintf('%6d %-11s %-14s Stop:%-1s %5sKB', ...
                logNumber,char(dateLabel),char(modeLabel),char(stopLabel),char(fileSize)));
            % UIのHTML描画でも連続する空白の文字幅を保つ。
            label = replace(label," ",string(char(160)));
        end

        % メタ情報をキー名で検索し、欠落・重複は空文字列として返す。
        % 引数 parameters: ヘッダの文字列配列、key: 検索するキー名。
        % 戻り値 value: 前後の空白を除いた値（単位は元メタ情報に従う）。
        function value = logMetadataValue(app,parameters,key)
            value = "";
            matches = 0;
            for parameter = reshape(parameters,1,[])
                text = char(parameter);
                equals = strfind(text,'=');
                if ~isempty(equals) && strcmp(strtrim(text(1:equals(1)-1)),key)
                    matches = matches+1;
                    value = strtrim(string(text(equals(1)+1:end)));
                end
            end
            if matches~=1
                value = "";
            end
        end


        % ログファイル一覧の取得
        function isSuccess = getLogfiles(app)
            isSuccess = false;
            selectedFile = string(app.LogDropDown.Value);
            try
                if ~isfolder(app.rootDir)
                    error("MATLAB:cd:NonExistentFolder","The folder does not exist.");
                end
                fileList = struct2table(dir(fullfile(app.rootDir,'*.csv')));
                if isempty(fileList)
                    error("logAnalysis:NoLogFiles","読み込み可能なログファイルがありません。");
                end
                % ソート用の番号と読み込み用の元ファイル名を分離する
                fileNames = string(fileList.name);
                logNumbers = str2double(erase(fileNames,lettersPattern | characterListPattern(".")));
                valid = ~isnan(logNumbers) & fileList.bytes ~= 0;
                fileList = fileList(valid,:);
                fileNames = fileNames(valid);
                logNumbers = logNumbers(valid);
                if isempty(fileNames)
                    error("logAnalysis:NoLogFiles","読み込み可能なログファイルがありません。");
                end
                [~,order] = sort(logNumbers,'descend');
                fileList = fileList(order,:);
                fileNames = fileNames(order);
                logNumbers = logNumbers(order);
                fileSize = string(round(fileList.bytes/1000));
                labels = strings(numel(fileNames),1);
                for fileIndex = 1:numel(fileNames)
                    filePath = fullfile(app.rootDir,fileNames(fileIndex));
                    labels(fileIndex) = getLogListLabel(app,filePath,logNumbers(fileIndex),fileSize(fileIndex));
                end

                % 表示文字列が変わってもファイル名で選択を保持する
                app.LogDropDown.ItemsData = {};
                app.LogDropDown.Items = labels;
                app.LogDropDown.ItemsData = cellstr(fileNames);
                if ismember(selectedFile,fileNames)
                    app.LogDropDown.Value = char(selectedFile);
                else
                    % 選択ログが削除された場合は一覧の先頭を選ぶ
                    app.LogDropDown.Value = char(fileNames(1));
                end
                app.LogDropDown.Enable = "on";
                app.Lamp.Color = "green";
                app.TextArea.Value = "";
                isSuccess = true;
            catch ME
                app.LogDropDown.ItemsData = {};
                app.LogDropDown.Items = {'not found files'};
                app.LogDropDown.Enable = "off";
                app.Lamp.Color = "red";
                setTexttarea(app,{ME.identifier,ME.message});
                if strcmp(ME.identifier,'MATLAB:cd:NonExistentFolder')
                    app.FolderButton.Text = "not found folder";
                end
            end
        end

        % フォルダ変更を読み込みまで実行し、失敗したら以前の表示状態へ戻す
        % 引数 folder: 選択されたフォルダ（キャンセル時は0）
        % 戻り値 isSuccess: 更新が完了したか
        function isSuccess = changeLogFolder(app,folder)
            isSuccess = false;
            if isequal(folder,0) || isempty(folder)
                return;
            end
            propertiesToSave = {'rootDir','log','time','dt','velocity','distance','distanceFromEquation','angularVelocity', ...
                'parameter','course','shortCutCourse'};
            previous = cell(size(propertiesToSave));
            for itemIndex = 1:numel(propertiesToSave)
                previous{itemIndex} = app.(propertiesToSave{itemIndex});
            end
            controls = {app.LogDropDown,app.Data1DropDown,app.Data2DropDown,app.Data3DropDown, ...
                app.Data4DropDown,app.Data5DropDown,app.DataAnalysisLDropDown,app.DataAnalysisRDropDown, ...
                app.Plotflag1DropDown,app.Plotflag2DropDown,app.Plotflag3DropDown};
            items = cellfun(@(control)control.Items,controls,'UniformOutput',false);
            values = cellfun(@(control)control.Value,controls,'UniformOutput',false);
            itemsData = app.LogDropDown.ItemsData;
            logEnable = app.LogDropDown.Enable;
            folderLabel = app.FolderButton.Text;
            sliderLimits = app.TimesSlider.Limits;
            sliderValue = app.TimesSlider.Value;
            sliderTicks = app.TimesSlider.MajorTicks;
            sliderLabels = app.TimesSlider.MajorTickLabels;
            metadata = app.TextArea_2.Value;
            app.rootDir = string(folder);
            if reload(app,"ButtonPushed")
                [~,folderName] = fileparts(char(folder));
                app.FolderButton.Text = folderName;
                isSuccess = true;
                return;
            end
            for itemIndex = 1:numel(propertiesToSave)
                app.(propertiesToSave{itemIndex}) = previous{itemIndex};
            end
            app.LogDropDown.ItemsData = {};
            for itemIndex = 1:numel(controls)
                controls{itemIndex}.Items = items{itemIndex};
                if itemIndex ~= 1
                    controls{itemIndex}.Value = values{itemIndex};
                end
            end
            app.LogDropDown.ItemsData = itemsData;
            app.LogDropDown.Value = values{1};
            app.LogDropDown.Enable = logEnable;
            app.FolderButton.Text = folderLabel;
            app.TextArea_2.Value = metadata;
            app.TimesSlider.Limits = sliderLimits;
            app.TimesSlider.Value = sliderValue;
            app.TimesSlider.MajorTicks = sliderTicks;
            app.TimesSlider.MajorTickLabels = sliderLabels;
            % 読込後の描画失敗で消えた表示も、保存したデータから復元する
            if ~isempty(app.course)
                plotCourse(app);
                plotVariableGraphs(app);
                procData(app,"left");
            end
            app.Lamp.Color = "red";
        end

        % 行数に応じた重複しない時間スライダー目盛りを設定する
        function configureTimeSlider(app)
            rowCount = numel(app.time);
            app.TimesSlider.Limits = [1 rowCount];
            app.TimesSlider.Value = min(rowCount,max(1,round(app.TimesSlider.Value)));
            ticks = unique(round(linspace(1,rowCount,min(7,rowCount))));
            app.TimesSlider.MajorTicks = ticks;
            app.TimesSlider.MinorTicks = [];
            app.TimesSlider.MajorTickLabels = cellstr(compose('%.4g',app.time(ticks)))';
        end

        % 軌跡の外周に均等な余白を付け、表示範囲の中央へ配置する
        function applyCourseLimits(app)
            if ~isempty(app.course)
                padding = 100; % 軌跡外周の余白[mm]
                bounds = [min(app.course.x)-padding max(app.course.x)+padding; ...
                    min(app.course.y)-padding max(app.course.y)+padding];
            else
                bounds = app.courseSize;
                if strcmp(app.SwitchDirection.Value,'CCW')
                    bounds = [-bounds(:,2) -bounds(:,1)];
                end
            end
            app.UIAxisCourse.XLim = bounds(1,:);
            app.UIAxisCourse.YLim = bounds(2,:);
        end

        % 数値列を検証し、描画・解析へ渡すdouble列ベクトルを取得する
        % 引数 name: ログの列名、戻り値 data: 有限な実数列
        function data = getNumericLogColumn(app,name)
            if ~ismember(name,app.log.Properties.VariableNames)
                error("logAnalysis:MissingColumn","ログ列%sがありません。",name);
            end
            data = app.log.(name);
            if ~isnumeric(data) || ~isreal(data) || ~iscolumn(data) || ...
                    numel(data) ~= numel(app.time) || any(~isfinite(data))
                error("logAnalysis:InvalidColumn","列%sには行数の一致する有限な実数が必要です。欠損値と列の型を確認してください。",name);
            end
            data = double(data);
        end

        % ログ変数一覧を取得
        function valnames = getLogvalues(app)
            valnames = app.log.Properties.VariableNames;
            % 列順に依存せず、描画可能な実数の数値列をすべて表示する
            valid = cellfun(@(name)isnumeric(app.log.(name)) && ...
                isreal(app.log.(name)) && iscolumn(app.log.(name)),valnames);
            valnames = valnames(valid);
            app.Data1DropDown.Items = valnames;
            app.Data2DropDown.Items = valnames;
            app.Data3DropDown.Items = valnames;
            app.Data4DropDown.Items = valnames;
            app.Data5DropDown.Items = valnames;
            app.Plotflag1DropDown.Items = valnames;
            app.Plotflag2DropDown.Items = valnames;
            app.Plotflag3DropDown.Items = valnames;
            app.DataAnalysisLDropDown.Items = [{'none'} valnames];
            app.DataAnalysisRDropDown.Items = [{'none'} valnames];
        end

        % テキストエリアに文字列を表示する
        function setTexttarea(app, str)
            % stringとcharが混在してもTextAreaが受け付けるcellstrに統一する
            messages = cellstr(string(str));
            app.textarea = [app.textarea reshape(messages,1,[])];
            app.TextArea.Value = app.textarea;  % テキストエリアに表示
            scroll(app.TextArea,'bottom');      % 最下部までスクロール
        end
        
        % コース座標の計算
        function isSuccess = calcCourseplot(app)
            isSuccess = false;
            if isempty(app.time) || isempty(app.velocity) || isempty(app.angularVelocity)
                return;
            end
            try
                % 1ms積算角度があるログは、疎な瞬時角速度から再積分しない。
                % imuAngle_Zは常にdeg。rad/sチェックは従来の角速度入力だけに適用する。
                if ismember('imuAngle_Z',app.log.Properties.VariableNames) || app.distanceFromEquation
                    if ismember('imuAngle_Z',app.log.Properties.VariableNames)
                        angle = double(app.log.imuAngle_Z) .* app.kgyro;
                    else
                        % 保存角度がない旧ログだけ角速度を積分する。
                        angle = cumtrapz(app.time,app.angularVelocity .* app.kgyro);
                        if app.radsCheckBox.Value, angle = rad2deg(angle); end
                    end
                    % 距離・速度の入力元はSettingタブの速度換算式に統一する。
                    % diff時は累積距離を直接使い、再積分による誤差や区間ずれを避ける。
                    distanceStep = diff([0;app.distance]);
                    if any(~isfinite(distanceStep))
                        error("logAnalysis:InvalidDistance","累積距離にInfまたはNaNがあります。");
                    end
                    % 負の距離差も保持し、先頭はスタート原点・角度0から積算する。
                    heading = ([0;angle(1:end-1)] + angle) ./ 2 + app.rotate;
                    x = cumsum(distanceStep .* sind(heading));
                    y = cumsum(distanceStep .* cosd(heading));
                elseif app.radsCheckBox.Value
                    % 積算角度のない旧ログは従来の換算式を使用する。
                    angle = cumtrapz(app.time,app.angularVelocity .* app.kgyro);
                    angle = angle + deg2rad(app.rotate);
                    x = cumtrapz(app.time,app.velocity .* sin(angle));
                    y = cumtrapz(app.time,app.velocity .* cos(angle));
                else
                    angle = cumtrapz(app.time,app.angularVelocity .* app.kgyro);
                    angle = angle + app.rotate;
                    x = cumtrapz(app.time,app.velocity .* sind(angle));
                    y = cumtrapz(app.time,app.velocity .* cosd(angle));
                end
                if any(~isfinite(angle)) || any(~isfinite(x)) || any(~isfinite(y))
                    error("logAnalysis:InvalidCourse","軌跡計算結果にInfまたはNaNがあります。換算式と補正係数を確認してください。");
                end
                % timetable変数作成
                app.course = timetable(seconds(app.time),x,y,app.velocity,'VariableNames',{'x','y','velocity'});
                app.shortCutCourse = []; % 基準軌跡が変わったら旧座標を無効にする
                isSuccess = true;
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end

        % ショートカット軌跡座標の計算
        function isSuccess = calcShortCutplot(app)
            isSuccess = false;
            app.shortCutCourse = [];
            if isempty(app.course)
                return;
            end
            try
                % windowが1未満の場合は処理を中断
                app.ShortCutwindowSlider.Value = round(app.ShortCutwindowSlider.Value);
                if app.ShortCutwindowSlider.Value < 1
                    app.Lamp.Color = "red";     % ステータスランプ赤
                    setTexttarea(app, {"windowの値が1未満です", "1以上に設定してください"});
                    return;
                end
                % 座標算出
                x = movmean(app.course.x,app.ShortCutwindowSlider.Value);
                y = movmean(app.course.y,app.ShortCutwindowSlider.Value);
                % timetable変数作成
                app.shortCutCourse = timetable(app.course.Time,x,y,app.course.velocity,'VariableNames',{'x','y','velocity'});
                isSuccess = true;
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message})
            end
        end
       
        % コース描画
        function isSuccess = plotCourse(app)
            isSuccess = false;
            if isempty(app.course)
                return;
            end
            % 表示するたびに現在の基準軌跡とwindowから座標を更新する
            if strcmp(app.ShortCutplotSwitch.Value,"On") && ~calcShortCutplot(app)
                return;
            end
            try
                % 初回だけ作成し、以後は座標と速度色を更新する
                previousNextPlot = app.UIAxisCourse.NextPlot;
                app.UIAxisCourse.NextPlot = 'add';
                cleanupAxes = onCleanup(@()set(app.UIAxisCourse,'NextPlot',previousNextPlot));
                if isempty(app.coursePlot) || ~isgraphics(app.coursePlot)
                    app.coursePlot = scatter(app.UIAxisCourse,app.course.x,app.course.y, ...
                        36,app.course.velocity, ...
                        'ButtonDownFcn',@(src,evnt)getTimeIndex(app,src,evnt));
                else
                    set(app.coursePlot,'XData',app.course.x,'YData',app.course.y, ...
                        'CData',app.course.velocity);
                end
                applyCourseLimits(app);
                daspect(app.UIAxisCourse,[1 1 1]);
                app.UIAxisCourse.CLimMode = 'auto';

                if isempty(app.courseColorbar) || ~isgraphics(app.courseColorbar)
                    app.courseColorbar = colorbar(app.UIAxisCourse);
                    app.courseColorbar.Label.String = 'velocity[mm/s]';
                    app.courseColorbar.Label.FontSize = 12;
                end

                layoutCourseView(app);

                if strcmp(app.ShortCutplotSwitch.Value,"On")
                    if isempty(app.shortCutPlot) || ~isgraphics(app.shortCutPlot)
                        app.shortCutPlot = scatter(app.UIAxisCourse,app.shortCutCourse.x, ...
                            app.shortCutCourse.y,'HitTest','off','PickableParts','none');
                    else
                        set(app.shortCutPlot,'XData',app.shortCutCourse.x, ...
                            'YData',app.shortCutCourse.y,'Visible','on');
                    end
                elseif ~isempty(app.shortCutPlot) && isgraphics(app.shortCutPlot)
                    app.shortCutPlot.Visible = 'off';
                end

                if ~plotFlagPoint(app)
                    return;
                end
                selectCourseIndex(app,app.TimesSlider.Value);
                isSuccess = true;
            catch ME
                app.Lamp.Color = "red";     % ステータスランプ赤
                % エラー表示
                for num = 1:1:length(ME.stack)
                    % setTexttarea(app, {ME.stack(num,1).name,num2str(ME.stack(num,1).line)})
                end
                setTexttarea(app, {ME.identifier,ME.message});
            end
        end

        % 定数線の表示
        function setXlines(app,time)
            axesList = {app.UIAxes1,app.UIAxes2,app.UIAxes3,app.UIAxes4,app.UIAxes5,app.UIAxesAnalysis};
            for itemIndex = 1:numel(axesList)
                if isempty(app.xlines{itemIndex}) || ~isgraphics(app.xlines{itemIndex})
                    app.xlines{itemIndex} = xline(axesList{itemIndex},time, ...
                        'HitTest','off','PickableParts','none');
                else
                    app.xlines{itemIndex}.Value = time;
                end
            end
        end
        
        % ログの取得、再プロット
        function isSuccess = reload(app,EventName)
            isSuccess = false;
            try
            if isempty(app.rootDir) || isequal(app.rootDir,0)
                folder = uigetdir;
                if isequal(folder,0)
                    return;
                end
                app.rootDir = folder;
            end
            if ~isempty(app.rootDir) && ~isequal(app.rootDir,0)
                if strcmp(EventName,"ButtonPushed")
                    if ~getLogfiles(app)
                        return;
                    end
                end
                if ~getLogdata(app)
                    return;
                end
                isSuccess = initializeLogView(app,[]);
            end
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"ログ更新失敗",ME.identifier,ME.message});
            end
        end

        % 起動と再読み込みで共通の列選択・軸・スライダー・描画を初期化する
        % 引数 settingsMap: 起動時の保存済み選択（通常更新は空）
        % 戻り値 isSuccess: 全計算と描画が成功したか
        function isSuccess = initializeLogView(app,settingsMap)
            isSuccess = false;
            try
                getLogvalues(app);
                if ~isempty(settingsMap)
                    restoreLogSelections(app,settingsMap);
                end
                configureTimeSlider(app);
                axesList = {app.UIAxes1,app.UIAxes2,app.UIAxes3,app.UIAxes4,app.UIAxes5,app.UIAxesAnalysis};
                for itemIndex = 1:numel(axesList)
                    axesList{itemIndex}.XLim = [0 app.time(end)];
                end
                if app.radsCheckBox.Value
                    app.angularVelocitydegsLabel.Text = 'angularVelocity [rad/s] = ';
                else
                    app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
                end
                % claを使わず、既存オブジェクトのデータを更新する
                if ~calcCourseplot(app) || ~plotCourse(app) || ...
                        ~plotVariableGraphs(app) || ~procData(app,"left")
                    return;
                end
                isSuccess = true;
                app.Lamp.Color = "green";
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"表示初期化失敗",ME.identifier,ME.message});
            end
        end

        % 同じ軸の線を再利用し、削除された場合だけ作り直す
        % 引数 graphAxes: 描画軸、plotHandle: 既存線、data: Y座標
        % 戻り値 plotHandle: 更新後の線
        function plotHandle = updateDataLine(app,graphAxes,plotHandle,data)
            if isempty(plotHandle) || ~isgraphics(plotHandle)
                previousNextPlot = graphAxes.NextPlot;
                graphAxes.NextPlot = 'add';
                cleanupAxes = onCleanup(@()set(graphAxes,'NextPlot',previousNextPlot));
                plotHandle = plot(graphAxes,app.time,data);
            else
                set(plotHandle,'XData',app.time,'YData',data,'Visible','on');
            end
        end

        % 設定用変数の保存
        function [isSuccess,errorMessage] = saveVars(app)
            isSuccess = false;
            errorMessage = "";
            try
            % 設定をCSVファイルに書き出す
			settings = {
                "logFolderDir", app.rootDir;
				"timeEquation", app.timeEditField.Value;
				"velocityEquation", app.velocityEditField.Value;
                "diffCheckBox", string(app.diffCheckBox.Value);
				"angularVelocityEquation", app.angularVelocityEditField.Value;
				"radsCheckBox", string(app.radsCheckBox.Value);
				"switchDirection", app.SwitchDirection.Value;
				"data1", string(app.Data1DropDown.Value);
				"data2", string(app.Data2DropDown.Value);
				"data3", string(app.Data3DropDown.Value);
                "data4", string(app.Data4DropDown.Value);
                "data5", string(app.Data5DropDown.Value);
				"analysisLeft", string(app.DataAnalysisLDropDown.Value);
				"analysisRight", string(app.DataAnalysisRDropDown.Value);
				"smoothLeft", string(app.smoothLDropDown.Value);
				"smoothRight", string(app.smoothRDropDown.Value);
				"windowLeft", string(app.windowLSpinner.Value);
				"windowRight", string(app.windowRSpinner.Value);
				"normalizeLeft", string(app.normalizeLSwitch.Value);
				"normalizeRight", string(app.normalizeRSwitch.Value);
				"detrendLeft", string(app.detrendLSwitch.Value);
				"detrendRight", string(app.detrendRSwitch.Value);
				"ylimMatch", string(app.ylimmatchSwitch.Value);
				"plotSwitch", string(app.ShortCutplotSwitch.Value);
				"windowSlider", string(app.ShortCutwindowSlider.Value)
			};
			settingsTable = cell2table(settings,"VariableNames",{'key','value'}); % table作成
                folderPath = app.csvFilePathEditer.Value;
                if isempty(folderPath)
                    folderPath = uigetdir;
                    if isequal(folderPath,0)
                        setTexttarea(app,{"設定保存をキャンセルしました。"});
                        return;
                    end
                    app.csvFilePathEditer.Value = folderPath;
                end
                if ~isfolder(folderPath)
                    error("logAnalysis:SettingsSaveFailed","設定の保存先フォルダが存在しません: %s",folderPath);
                end
                filePath = fullfile(folderPath,app.csvFileName);
                if isfolder(filePath)
                    error("logAnalysis:SettingsSaveFailed","設定ファイルと同名のフォルダがあります: %s",filePath);
                end
                tempFile = [tempname(folderPath) '.csv'];
                cleanupFile = onCleanup(@()cleanupSettingsFile(app,tempFile));
                writetable(settingsTable,tempFile);
                [moved,message] = movefile(tempFile,filePath,'f');
                if ~moved
                    error("logAnalysis:SettingsSaveFailed","設定ファイルの置換に失敗しました: %s",message);
                end
                isSuccess = true;
                setTexttarea(app,{"設定保存完了"});
            catch ME
                errorMessage = string(ME.message);
                app.Lamp.Color = "red";
                setTexttarea(app,{"設定保存失敗",ME.identifier,ME.message});
            end
        end
        % データ処理
        function isSuccess = procData(app,pos)
            isSuccess = false;
            try
            % 両側を現在の設定で処理し、描画データと範囲計算を一致させる
            [varNameL,varDataL] = getAnalysisData(app,"left");
            [varNameR,varDataR] = getAnalysisData(app,"right");
            sides = {"left","right"};
            names = {varNameL,varNameR};
            data = {varDataL,varDataR};
            for sideIndex = 1:2
                yyaxis(app.UIAxesAnalysis,sides{sideIndex});
                ylabel(app.UIAxesAnalysis,names{sideIndex});
                if strcmp(names{sideIndex},"none")
                    if ~isempty(app.analysisPlots{sideIndex}) && isgraphics(app.analysisPlots{sideIndex})
                        app.analysisPlots{sideIndex}.Visible = 'off';
                    end
                else
                    app.analysisPlots{sideIndex} = updateDataLine(app,app.UIAxesAnalysis, ...
                        app.analysisPlots{sideIndex},data{sideIndex});
                end
                app.UIAxesAnalysis.YLimMode = "auto";
            end

            if strcmp(app.ylimmatchSwitch.Value,"On") && ...
                    ~strcmp(varNameL,"none") && ~strcmp(varNameR,"none")
                values = [varDataL(:);varDataR(:)];
                values = values(isfinite(values));
                if ~isempty(values)
                    limmin = min(values);
                    limmax = max(values);
                    if limmin == limmax
                        padding = max(1,abs(limmin)*0.1);
                    else
                        padding = (limmax-limmin)*0.1;
                    end
                    limits = [limmin-padding limmax+padding];
                    for sideIndex = 1:2
                        yyaxis(app.UIAxesAnalysis,sides{sideIndex});
                        app.UIAxesAnalysis.YLim = limits;
                    end
                end
            end
            index = round(app.TimesSlider.Value);
            setXlines(app,app.time(index));
            yyaxis(app.UIAxesAnalysis,pos);
            isSuccess = true;
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"解析処理失敗",ME.identifier,ME.message});
            end
        end

        % 左右の解析設定を適用したデータを取得する
        % 引数 pos: "left" または "right"
        % 戻り値 varName: 選択列名、varData: 加工後データ（none時は空）
        function [varName,varData] = getAnalysisData(app,pos)
            if strcmp(pos,"left")
                varName = app.DataAnalysisLDropDown.Value;
                method = app.smoothLDropDown.Value;
                window = app.windowLSpinner.Value;
                normalizeState = app.normalizeLSwitch.Value;
                detrendState = app.detrendLSwitch.Value;
            else
                varName = app.DataAnalysisRDropDown.Value;
                method = app.smoothRDropDown.Value;
                window = app.windowRSpinner.Value;
                normalizeState = app.normalizeRSwitch.Value;
                detrendState = app.detrendRSwitch.Value;
            end
            varData = [];
            if strcmp(varName,"none")
                return;
            end
            varData = getNumericLogColumn(app,varName);
            if ~strcmp(method,"none")
                varData = smoothdata(varData,method,window);
            end
            if strcmp(normalizeState,"On")
                varData = normalize(varData);
            end
            if strcmp(detrendState,"On")
                varData = detrend(varData);
            end
            if any(~isfinite(varData))
                error("logAnalysis:InvalidAnalysis","列%sの加工結果にInfまたはNaNがあります。",varName);
            end
        end
        % ログ行のインデックスで現在地・スライダー・定数線を更新する
        % 引数 index: 選択行番号（整数化して有効範囲に制限）
        function selectCourseIndex(app,index)
            if isempty(app.time) || isempty(app.course) || ...
                    ~isnumeric(index) || ~isscalar(index) || ~isfinite(index)
                return;
            end
            rowCount = min(numel(app.time),height(app.course));
            lowerIndex = max(1,ceil(app.TimesSlider.Limits(1)));
            upperIndex = min(rowCount,floor(app.TimesSlider.Limits(2)));
            if lowerIndex > upperIndex
                return;
            end
            index = min(upperIndex,max(lowerIndex,round(index)));
            app.TimesSlider.Value = index;
            setXlines(app,app.time(index));
            updateLineSensorImage(app,index);

            % 時刻の重複に依存しない行番号で座標を選択する
            x = app.course.x(index);
            y = app.course.y(index);
            if ~isfinite(x) || ~isfinite(y)
                return;
            end
            if ~isempty(app.marker) && isgraphics(app.marker)
                app.marker.XData = x;
                app.marker.YData = y;
            else
                previousHold = ishold(app.UIAxisCourse);
                hold(app.UIAxisCourse,'on');
                app.marker = scatter(app.UIAxisCourse,x,y,"m",'filled', ...
                    'HitTest','off','PickableParts','none');
                if ~previousHold
                    hold(app.UIAxisCourse,'off');
                end
            end
        end

        % 選択行を中心に前後2行のラインセンサー値を白黒画像へ変換する
        % 引数 index: 選択中のログ行。0番が左端、4096が白、0が黒
        function updateLineSensorImage(app,index)
            if isempty(app.LineSensorAxes) || ~isgraphics(app.LineSensorAxes)
                return;
            end
            rowIndices = index + (2:-1:-2); % 上が後、中央が現在、下が前
            validRows = rowIndices >= 1 & rowIndices <= height(app.log);
            colors = repmat(0.75,5,10,3); % 行不足・不正値は灰色
            labels = repmat({'--'},1,5);
            for row = find(validRows)
                labels{row} = sprintf('%d (%.3fs)',rowIndices(row),app.time(rowIndices(row)));
            end
            names = cellstr("lSensorCari" + string(0:9));
            columnsPresent = all(ismember(names,app.log.Properties.VariableNames));
            columnsNumeric = columnsPresent;
            if columnsPresent
                columnsNumeric = all(cellfun(@(name)isnumeric(app.log.(name)) && ...
                    isreal(app.log.(name)) && iscolumn(app.log.(name)),names));
            end
            if columnsNumeric
                values = double(app.log{rowIndices(validRows),names});
                gray = min(4096,max(0,values)) / 4096;
                gray(~isfinite(values)) = 0.75;
                colors(validRows,:,:) = repmat(gray,1,1,3);
                app.LineSensorAxes.Title.String = 'Line sensors (0: black / 4096: white)';
            else
                app.LineSensorAxes.Title.String = 'Line sensor columns unavailable';
            end
            if isempty(app.lineSensorImage) || ~isgraphics(app.lineSensorImage)
                previousNextPlot = app.LineSensorAxes.NextPlot;
                app.LineSensorAxes.NextPlot = 'add';
                cleanupAxes = onCleanup(@()set(app.LineSensorAxes,'NextPlot',previousNextPlot));
                app.lineSensorImage = image(app.LineSensorAxes,'XData',[0 9], ...
                    'YData',[1 5],'CData',colors,'HitTest','off','PickableParts','none');
            else
                app.lineSensorImage.CData = colors;
            end
            app.LineSensorAxes.XLim = [-0.5 9.5];
            app.LineSensorAxes.YLim = [0.5 5.5];
            app.LineSensorAxes.YDir = 'reverse';
            app.LineSensorAxes.YTickLabel = labels;
            if isempty(app.lineSensorSelection) || ~isgraphics(app.lineSensorSelection)
                previousNextPlot = app.LineSensorAxes.NextPlot;
                app.LineSensorAxes.NextPlot = 'add';
                cleanupSelection = onCleanup(@()set(app.LineSensorAxes,'NextPlot',previousNextPlot));
                app.lineSensorSelection = rectangle(app.LineSensorAxes, ...
                    'Position',[-0.5 2.5 10 1],'EdgeColor',[1 0 0],'LineWidth',1.5, ...
                    'HitTest','off','PickableParts','none');
            end
        end

        % クリック元の軸と座標から最も近いログ行を選択する
        % 引数 src: クリックされた描画オブジェクト、event: クリックイベント
        function getTimeIndex(app,src,event)
            if isempty(app.time) || isempty(app.course) || ~isgraphics(src)
                return;
            end
            sourceAxes = ancestor(src,'axes');
            if isempty(sourceAxes)
                return;
            end
            clickPoint = sourceAxes.CurrentPoint;
            % クリックイベントの座標がある場合はその値を使用する
            if (isstruct(event) && isfield(event,'IntersectionPoint')) || ...
                    (isobject(event) && isprop(event,'IntersectionPoint'))
                point = event.IntersectionPoint;
                if isnumeric(point) && numel(point) >= 2 && all(isfinite(point(1:2)))
                    clickPoint(1,1:2) = point(1:2);
                end
            end
            X = src.XData(:);
            if isa(src,'matlab.graphics.chart.primitive.Scatter') && ...
                    isequal(sourceAxes,app.UIAxisCourse)
                Y = src.YData(:);
                distances = (X-clickPoint(1,1)).^2 + (Y-clickPoint(1,2)).^2;
            elseif isa(src,'matlab.graphics.chart.primitive.Line') && ...
                    (isequal(sourceAxes,app.UIAxes1) || ...
                     isequal(sourceAxes,app.UIAxes2) || ...
                     isequal(sourceAxes,app.UIAxes3) || ...
                     isequal(sourceAxes,app.UIAxes4) || ...
                     isequal(sourceAxes,app.UIAxes5))
                if ~strcmp(app.graphPositionBotton.Value,"On")
                    return;
                end
                distances = abs(X-clickPoint(1,1));
            else
                return;
            end
            distances(~isfinite(distances)) = Inf;
            if isempty(distances) || all(isinf(distances))
                return;
            end
            [~,index] = min(distances);
            selectCourseIndex(app,index);
        end

        % 変数グラフを描画し、位置選択設定に応じてクリック処理を登録する
        % 引数 graphAxes: 描画先の軸、dataDropDown: 表示列の選択UI
        function isSuccess = plotVariableGraph(app,graphAxes,dataDropDown)
            isSuccess = false;
            if isempty(app.log) || isempty(app.time)
                return;
            end
            try
            data = getNumericLogColumn(app,dataDropDown.Value);
            axesList = {app.UIAxes1,app.UIAxes2,app.UIAxes3,app.UIAxes4,app.UIAxes5};
            plotIndex = find(cellfun(@(axesHandle)isequal(axesHandle,graphAxes),axesList),1);
            app.variablePlots{plotIndex} = updateDataLine(app,graphAxes, ...
                app.variablePlots{plotIndex},data);
            if strcmp(app.graphPositionBotton.Value,"On")
                app.variablePlots{plotIndex}.ButtonDownFcn = @(src,evnt)getTimeIndex(app,src,evnt);
            else
                app.variablePlots{plotIndex}.ButtonDownFcn = [];
            end
            graphAxes.YLimMode = 'auto';
            title(graphAxes,dataDropDown.Value);
            graphAxes.Title.Visible = "off";
            % 目盛りの桁数によらず5段の描画領域を同じ519×100pxに固定する。
            graphAxes.PositionConstraint = 'innerposition';
            graphAxes.PlotBoxAspectRatioMode = 'auto';
            graphAxes.DataAspectRatioMode = 'auto';
            graphAxes.InnerPosition = [44 62+(5-plotIndex)*169 519 100];
            isSuccess = true;
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"グラフ描画失敗",ME.identifier,ME.message});
            end
        end

        % 5つの変数グラフを更新し、選択位置を同期する
        function isSuccess = plotVariableGraphs(app)
            isSuccess = false;
            if ~plotVariableGraph(app,app.UIAxes1,app.Data1DropDown) || ...
                    ~plotVariableGraph(app,app.UIAxes2,app.Data2DropDown) || ...
                    ~plotVariableGraph(app,app.UIAxes3,app.Data3DropDown) || ...
                    ~plotVariableGraph(app,app.UIAxes4,app.Data4DropDown) || ...
                    ~plotVariableGraph(app,app.UIAxes5,app.Data5DropDown)
                return;
            end
            linkaxes([app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4 app.UIAxes5],'x');
            selectCourseIndex(app,app.TimesSlider.Value);
            isSuccess = true;
        end
        function getParameter(app)
            
        end
        
        function isSuccess = plotFlagPoint(app)
            isSuccess = false;
            if isempty(app.log) || isempty(app.course)
                return;
            end
            try
                colors = {'red','green','blue'};
                for itemIndex = 1:3
                    propertyName = sprintf('flag%d',itemIndex);
                    plotHandle = app.(propertyName);
                    enabled = app.(sprintf('Plotflag%denableCheckBox',itemIndex)).Value;
                    if ~enabled
                        if ~isempty(plotHandle) && isgraphics(plotHandle)
                            plotHandle.Visible = 'off';
                        end
                        continue;
                    end
                    columnName = app.(sprintf('Plotflag%dDropDown',itemIndex)).Value;
                    selected = getNumericLogColumn(app,columnName) > 0;
                    x = app.course.x(selected);
                    y = app.course.y(selected);
                    if isempty(plotHandle) || ~isgraphics(plotHandle)
                        previousNextPlot = app.UIAxisCourse.NextPlot;
                        app.UIAxisCourse.NextPlot = 'add';
                        cleanupAxes = onCleanup(@()set(app.UIAxisCourse,'NextPlot',previousNextPlot));
                        app.(propertyName) = scatter(app.UIAxisCourse,x,y,colors{itemIndex}, ...
                            'filled','HitTest','off','PickableParts','none');
                        clear cleanupAxes;
                    else
                        set(plotHandle,'XData',x,'YData',y,'Visible','on');
                    end
                end
                isSuccess = true;
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"フラグ描画失敗",ME.identifier,ME.message});
            end
        end
        % カラーバーと軌跡を同じ表示領域へ配置する（座標・寸法: px）。
        function layoutCourseView(app)
            if isequal(app.expand,1)
                width = 1152;
                colorbarX = 1187;
            else
                width = 555;
                colorbarX = 590;
            end
            if ~isempty(app.courseColorbar) && isgraphics(app.courseColorbar)
                app.courseColorbar.Units = 'pixels';
                app.courseColorbar.Position = [colorbarX 230 16 607];
            end
            app.UIAxisCourse.Position = [8 190 width 657];
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            settingsMap = [];
            try
                F = msgbox("設定ファイルを選択してください");
                [fileName,location] = uigetfile({'*.csv'});
                close(F);
                if ~isequal(fileName,0)
                    [loaded,settingsMap] = loadSettings(app,fullfile(location,fileName));
                    if ~loaded
                        return;
                    end
                else
                    F = msgbox("ログファイルの保存フォルダを選択してください");
                    app.rootDir = uigetdir;
                    close(F);
                end
            catch ME
                app.Lamp.Color = "red";
                setTexttarea(app,{"設定読込失敗",ME.identifier,ME.message});
                return;
            end
            if ~isempty(app.rootDir) && ~isequal(app.rootDir,0)
		        if ~getLogfiles(app)
                    return;
                end
                if ~getLogdata(app)
                    return;
                end
                initializeLogView(app,settingsMap);
            end
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            % 現在のログフォルダを選択画面の初期位置にする
            if (ischar(app.rootDir) || isstring(app.rootDir)) && ...
                    ~isempty(app.rootDir) && isfolder(app.rootDir)
                folder = uigetdir(char(app.rootDir));
            else
                folder = uigetdir;
            end
            if ~isequal(folder,0)
                changeLogFolder(app,folder);
            end
        end

        % Value changed function: LogDropDown
        function LogDropDownValueChanged(app, event)
            reload(app,event.EventName);
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            if reload(app,event.EventName)
                saveVars(app);
            end
        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            if isnumeric(event)
                changingValue = event;
            else
                changingValue = event.Value;
            end
            selectCourseIndex(app,changingValue);
        end

        % Value changed function: Data1DropDown
        function Data1DropDownValueChanged(app, event)
            plotVariableGraph(app,app.UIAxes1,app.Data1DropDown);
            selectCourseIndex(app,app.TimesSlider.Value);
        end

        % Value changed function: Data2DropDown
        function Data2DropDownValueChanged(app, event)
            plotVariableGraph(app,app.UIAxes2,app.Data2DropDown);
            selectCourseIndex(app,app.TimesSlider.Value);
        end

        % Value changed function: Data3DropDown
        function Data3DropDownValueChanged(app, event)
            plotVariableGraph(app,app.UIAxes3,app.Data3DropDown);
            selectCourseIndex(app,app.TimesSlider.Value);
        end

        % Value changed function: DataAnalysisLDropDown
        function DataAnalysisLDropDownValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: DataAnalysisRDropDown
        function DataAnalysisRDropDownValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: smoothLDropDown
        function smoothLDropDownValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: smoothRDropDown
        function smoothRDropDownValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: windowLSpinner
        function windowLSpinnerValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: windowRSpinner
        function windowRSpinnerValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: normalizeLSwitch
        function normalizeLSwitchValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: normalizeRSwitch
        function normalizeRSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: detrendLSwitch
        function detrendLSwitchValueChanged(app, event)
            procData(app,"left");
        end

        % Value changed function: detrendRSwitch
        function detrendRSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changed function: ylimmatchSwitch
        function ylimmatchSwitchValueChanged(app, event)
            procData(app,"right");
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.CourseajustgyroKnob.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            if calcCourseplot(app)
                plotCourse(app);
            end
        end

        % Value changing function: CourseajustgyroKnob
        function CourseajustgyroKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner.Value = changingValue;
            
            app.kgyro = changingValue; % 角度補正係数にノブの値を代入
            if calcCourseplot(app)
                plotCourse(app);
            end
        end

        % Value changed function: SwitchDirection
        function SwitchDirectionValueChanged(app, event)
            applyCourseLimits(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [saved,errorMessage] = saveVars(app);
            if ~saved && strlength(errorMessage)>0
                % 親UIを閉じても残る非モーダル警告で保存失敗を通知する
                warndlg("設定を保存できませんでした。" + newline + errorMessage, ...
                    "設定保存失敗",'non-modal');
            end
            delete(app)
        end

        % Value changed function: radsCheckBox
        function radsCheckBoxValueChanged(app, event)
            value = app.radsCheckBox.Value;
            if value == 0
                app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
            elseif value == 1
                app.angularVelocitydegsLabel.Text = 'angularVelocity [rad/s] = ';
            end
            if calcCourseplot(app)
                plotCourse(app);
            end
        end

        % Value changed function: graphPositionBotton
        function graphPositionBottonValueChanged(app, event)
            value = app.graphPositionBotton.Value;
            disp(value)
            plotVariableGraphs(app);
        end

        % Value changed function: ShortCutwindowSlider
        function ShortCutwindowSliderValueChanged(app, event)
            % windowSliderの値が1未満にならないよう補正
            if app.ShortCutwindowSlider.Value < 1
                app.ShortCutwindowSlider.Value = 1;
            end
            plotCourse(app);
        end

        % Value changed function: ShortCutplotSwitch
        function ShortCutplotSwitchValueChanged(app, event)
            plotCourse(app);
        end

        % Button pushed function: Button_Rotate
        function Button_RotatePushed(app, event)
            app.rotate = mod(app.rotate - 90,360);
            if calcCourseplot(app)
                plotCourse(app);
            end
        end

        % Button pushed function: Button_Expand
        function Button_ExpandPushed(app, event)
            if app.expand == 1
                app.expand = 0;

                app.TabGroup.Visible = "on";
                app.CourseajustgyroKnob.Visible = "on";
                app.Spinner.Visible = "on";
                app.SwitchDirection.Visible = "on";

                app.UIAxisCourse.Position = [8 190 555 657];
                app.LineSensorAxes.Position = [63 76 514 100];
                app.TimesSlider.Position = [63 42 514 3];
                app.TimesLabel.Position = [590 10 68 22];
            else
                app.expand = 1;

                app.TabGroup.Visible = "off";
                app.CourseajustgyroKnob.Visible = "off";
                app.Spinner.Visible = "off";
                app.SwitchDirection.Visible = "off";

                app.UIAxisCourse.Position = [8 190 1152 657];
                app.LineSensorAxes.Position = [63 76 1110 100];
                app.TimesSlider.Position = [63 42 1110 3];
                app.TimesLabel.Position = [1185 10 68 22];
            end
            layoutCourseView(app);
        end

        % Button pushed function: csvFilePathButton
        function csvFilePathButtonPushed(app, event)
            fileDir = uigetdir;
            if fileDir ~= 0
                app.csvFilePathEditer.Value = fileDir;
            end
        end

        % Window key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if strcmp(event.Key,'leftarrow')
                step = -1;
            elseif strcmp(event.Key,'rightarrow')
                step = 1;
            else
                return;
            end
            selectCourseIndex(app,round(app.TimesSlider.Value)+step);
        end

        % Value changed function: Plotflag1enableCheckBox
        function Plotflag1enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag2enableCheckBox
        function Plotflag2enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag3enableCheckBox
        function Plotflag3enableCheckBoxValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag1DropDown
        function Plotflag1DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag2DropDown
        function Plotflag2DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Plotflag3DropDown
        function Plotflag3DropDownValueChanged(app, event)
            plotFlagPoint(app); %　フラグをプロット
        end

        % Value changed function: Data4DropDown
        function Data4DropDownValueChanged(app, event)
            plotVariableGraph(app,app.UIAxes4,app.Data4DropDown);
            selectCourseIndex(app,app.TimesSlider.Value);
        end

        % Value changed function: Data5DropDown
        function Data5DropDownValueChanged(app, event)
            plotVariableGraph(app,app.UIAxes5,app.Data5DropDown);
            selectCourseIndex(app,app.TimesSlider.Value);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            % 下方向は次、上方向は前へホイール1目盛りにつき3行移動する
            step = 3 * event.VerticalScrollCount;
            selectCourseIndex(app,round(app.TimesSlider.Value)+step);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxisCourse = uiaxes(app.UIFigure);
            xlabel(app.UIAxisCourse, 'X[mm]')
            ylabel(app.UIAxisCourse, 'Y[mm]')
            zlabel(app.UIAxisCourse, 'Z[mm]')
            app.UIAxisCourse.XLim = [0 1];
            app.UIAxisCourse.YLim = [0 1];
            app.LineSensorAxes = uiaxes(app.UIFigure);
            title(app.LineSensorAxes, 'Line sensors (0: black / 4096: white)')
            app.LineSensorAxes.XLim = [-0.5 9.5];
            app.LineSensorAxes.YLim = [0.5 5.5];
            app.LineSensorAxes.YDir = 'reverse';
            app.LineSensorAxes.XTick = [0 1 2 3 4 5 6 7 8 9];
            app.LineSensorAxes.YTick = [1 2 3 4 5];
            app.Lamp = uilamp(app.UIFigure);
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'path';
            app.FolderpathLabel = uilabel(app.UIFigure);
            app.FolderpathLabel.Text = 'Folder';
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time [s]';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            app.LogDropDownLabel = uilabel(app.UIFigure);
            app.LogDropDownLabel.Text = 'Log';
            app.LogDropDown = uidropdown(app.UIFigure);
            app.LogDropDown.Items = {'option'};
            app.LogDropDown.ValueChangedFcn = createCallbackFcn(app, @LogDropDownValueChanged, true);
            app.LogDropDown.Value = 'option';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.UIAxes3 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes3, 'Time [s]')
            app.UIAxes3.PositionConstraint = 'innerposition';
            app.UIAxes2 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes2, 'Time [s]')
            app.UIAxes2.PositionConstraint = 'innerposition';
            app.UIAxes1 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes1, 'Time [s]')
            app.UIAxes1.PositionConstraint = 'innerposition';
            app.UIAxes4 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes4, 'Time [s]')
            app.UIAxes4.PositionConstraint = 'innerposition';
            app.UIAxes5 = uiaxes(app.GraphsTab);
            xlabel(app.UIAxes5, 'Time [s]')
            app.UIAxes5.PositionConstraint = 'innerposition';
            app.Data2DropDownLabel = uilabel(app.GraphsTab);
            app.Data2DropDownLabel.Text = 'Data2';
            app.Data2DropDown = uidropdown(app.GraphsTab);
            app.Data2DropDown.ValueChangedFcn = createCallbackFcn(app, @Data2DropDownValueChanged, true);
            app.Data3DropDownLabel = uilabel(app.GraphsTab);
            app.Data3DropDownLabel.Text = 'Data3';
            app.Data3DropDown = uidropdown(app.GraphsTab);
            app.Data3DropDown.ValueChangedFcn = createCallbackFcn(app, @Data3DropDownValueChanged, true);
            app.Data1DropDownLabel = uilabel(app.GraphsTab);
            app.Data1DropDownLabel.Text = 'Data1';
            app.Data1DropDown = uidropdown(app.GraphsTab);
            app.Data1DropDown.ValueChangedFcn = createCallbackFcn(app, @Data1DropDownValueChanged, true);
            app.Data4DropDownLabel = uilabel(app.GraphsTab);
            app.Data4DropDownLabel.Text = 'Data4';
            app.Data4DropDown = uidropdown(app.GraphsTab);
            app.Data4DropDown.Items = {'gyroVal_Z'};
            app.Data4DropDown.ValueChangedFcn = createCallbackFcn(app, @Data4DropDownValueChanged, true);
            app.Data4DropDown.Value = 'gyroVal_Z';
            app.Data5DropDownLabel = uilabel(app.GraphsTab);
            app.Data5DropDownLabel.Text = 'Data5';
            app.Data5DropDown = uidropdown(app.GraphsTab);
            app.Data5DropDown.Items = {'encCurrentCorr_p'};
            app.Data5DropDown.ValueChangedFcn = createCallbackFcn(app, @Data5DropDownValueChanged, true);
            app.Data5DropDown.Value = 'encCurrentCorr_p';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxesAnalysis = uiaxes(app.AnalysisTab);
            xlabel(app.UIAxesAnalysis, 'Time [s]')
            app.DataLabel = uilabel(app.AnalysisTab);
            app.DataLabel.Text = 'Data ';
            app.DataDropDownLabel_2 = uilabel(app.AnalysisTab);
            app.DataDropDownLabel_2.Text = 'Data';
            app.smoothDropDown_2Label = uilabel(app.AnalysisTab);
            app.smoothDropDown_2Label.Text = 'smooth';
            app.windowSpinner_2Label = uilabel(app.AnalysisTab);
            app.windowSpinner_2Label.Text = 'window';
            app.windowRSpinner = uispinner(app.AnalysisTab);
            app.windowRSpinner.Limits = [2 Inf];
            app.windowRSpinner.ValueChangedFcn = createCallbackFcn(app, @windowRSpinnerValueChanged, true);
            app.windowRSpinner.Value = 2;
            app.smoothRDropDown = uidropdown(app.AnalysisTab);
            app.smoothRDropDown.Items = {'none', 'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.smoothRDropDown.ValueChangedFcn = createCallbackFcn(app, @smoothRDropDownValueChanged, true);
            app.smoothRDropDown.Value = 'none';
            app.DataAnalysisRDropDown = uidropdown(app.AnalysisTab);
            app.DataAnalysisRDropDown.ValueChangedFcn = createCallbackFcn(app, @DataAnalysisRDropDownValueChanged, true);
            app.windowSpinnerLabel = uilabel(app.AnalysisTab);
            app.windowSpinnerLabel.Text = 'window';
            app.windowLSpinner = uispinner(app.AnalysisTab);
            app.windowLSpinner.Limits = [2 Inf];
            app.windowLSpinner.ValueChangedFcn = createCallbackFcn(app, @windowLSpinnerValueChanged, true);
            app.windowLSpinner.Value = 2;
            app.FunctionLabel = uilabel(app.AnalysisTab);
            app.FunctionLabel.Text = 'smooth';
            app.smoothLDropDown = uidropdown(app.AnalysisTab);
            app.smoothLDropDown.Items = {'none', 'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.smoothLDropDown.ValueChangedFcn = createCallbackFcn(app, @smoothLDropDownValueChanged, true);
            app.smoothLDropDown.Value = 'none';
            app.DataAnalysisLDropDown = uidropdown(app.AnalysisTab);
            app.DataAnalysisLDropDown.ValueChangedFcn = createCallbackFcn(app, @DataAnalysisLDropDownValueChanged, true);
            app.normalizeSwitchLabel = uilabel(app.AnalysisTab);
            app.normalizeSwitchLabel.Text = 'normalize';
            app.normalizeLSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.normalizeLSwitch.ValueChangedFcn = createCallbackFcn(app, @normalizeLSwitchValueChanged, true);
            app.detrendSwitchLabel = uilabel(app.AnalysisTab);
            app.detrendSwitchLabel.Text = 'detrend';
            app.detrendLSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.detrendLSwitch.ValueChangedFcn = createCallbackFcn(app, @detrendLSwitchValueChanged, true);
            app.normalizeSwitch_2Label = uilabel(app.AnalysisTab);
            app.normalizeSwitch_2Label.Text = 'normalize';
            app.normalizeRSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.normalizeRSwitch.ValueChangedFcn = createCallbackFcn(app, @normalizeRSwitchValueChanged, true);
            app.detrendSwitch_2Label = uilabel(app.AnalysisTab);
            app.detrendSwitch_2Label.Text = 'detrend';
            app.detrendRSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.detrendRSwitch.ValueChangedFcn = createCallbackFcn(app, @detrendRSwitchValueChanged, true);
            app.ylimmatchSwitchLabel = uilabel(app.AnalysisTab);
            app.ylimmatchSwitchLabel.Text = 'ylim match';
            app.ylimmatchSwitch = uiswitch(app.AnalysisTab, 'slider');
            app.ylimmatchSwitch.ValueChangedFcn = createCallbackFcn(app, @ylimmatchSwitchValueChanged, true);
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.TextArea_2 = uitextarea(app.ParameterTab);
            app.consoleTab = uitab(app.TabGroup);
            app.consoleTab.Title = 'console';
            app.TextArea = uitextarea(app.consoleTab);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.velocitymmsLabel = uilabel(app.SettingTab);
            app.velocitymmsLabel.Text = 'velocity [mm/s] = ';
            app.velocityEditField = uieditfield(app.SettingTab, 'text');
            app.velocityEditField.Value = 'encCurrentCorr_p / 58.019 * 1000';
            app.angularVelocitydegsLabel = uilabel(app.SettingTab);
            app.angularVelocitydegsLabel.Text = 'angularVelocity [deg/s] = ';
            app.angularVelocityEditField = uieditfield(app.SettingTab, 'text');
            app.angularVelocityEditField.Value = 'gyroVal_Z';
            app.velocitymmsLabel_2 = uilabel(app.SettingTab);
            app.velocitymmsLabel_2.Text = 'time [s] = ';
            app.timeEditField = uieditfield(app.SettingTab, 'text');
            app.timeEditField.Value = 'cntlog / 1000';
            app.TranslateequationsLabel = uilabel(app.SettingTab);
            app.TranslateequationsLabel.Text = 'Translate equations';
            app.radsCheckBox = uicheckbox(app.SettingTab);
            app.radsCheckBox.ValueChangedFcn = createCallbackFcn(app, @radsCheckBoxValueChanged, true);
            app.radsCheckBox.Text = '[rad/s]';
            app.SwitchLabel = uilabel(app.SettingTab);
            app.SwitchLabel.Text = {'Click a variable graph to show'; 'the position on the course'};
            app.graphPositionBotton = uiswitch(app.SettingTab, 'slider');
            app.graphPositionBotton.ValueChangedFcn = createCallbackFcn(app, @graphPositionBottonValueChanged, true);
            app.Spinner = uispinner(app.SettingTab);
            app.Spinner.Step = 0.001;
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [0.9 1.4];
            app.Spinner.Value = 1;
            app.CourseajustgyroKnobLabel = uilabel(app.SettingTab);
            app.CourseajustgyroKnobLabel.Text = 'Course ajust (gyro)';
            app.CourseajustgyroKnob = uiknob(app.SettingTab, 'continuous');
            app.CourseajustgyroKnob.Limits = [0.9 1.4];
            app.CourseajustgyroKnob.ValueChangingFcn = createCallbackFcn(app, @CourseajustgyroKnobValueChanging, true);
            app.CourseajustgyroKnob.Value = 1;
            app.SwitchDirection = uiswitch(app.SettingTab, 'toggle');
            app.SwitchDirection.Items = {'CCW', 'CW'};
            app.SwitchDirection.ValueChangedFcn = createCallbackFcn(app, @SwitchDirectionValueChanged, true);
            app.SwitchDirection.Value = 'CW';
            app.Label = uilabel(app.SettingTab);
            app.Label.Text = 'Course Direction';
            app.csvFilePathEditer = uieditfield(app.SettingTab, 'text');
            app.csvFilePathButton = uibutton(app.SettingTab, 'push');
            app.csvFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @csvFilePathButtonPushed, true);
            app.csvFilePathButton.Text = 'path';
            app.SettingCSVfilepathLabel = uilabel(app.SettingTab);
            app.SettingCSVfilepathLabel.Text = 'Setting CSV file path';
            app.ShortCutplotSwitchLabel = uilabel(app.SettingTab);
            app.ShortCutplotSwitchLabel.Text = 'ShortCut plot';
            app.ShortCutplotSwitch = uiswitch(app.SettingTab, 'slider');
            app.ShortCutplotSwitch.ValueChangedFcn = createCallbackFcn(app, @ShortCutplotSwitchValueChanged, true);
            app.ShortCutwindowSlider = uislider(app.SettingTab);
            app.ShortCutwindowSlider.Limits = [1 50];
            app.ShortCutwindowSlider.MajorTicks = [1 8 15 22 29 36 43 50];
            app.ShortCutwindowSlider.ValueChangedFcn = createCallbackFcn(app, @ShortCutwindowSliderValueChanged, true);
            app.ShortCutwindowSlider.MinorTicks = [1 2.4 3.8 5.2 6.6 8 9.4 10.8 12.2 13.6 15 16.4 17.8 19.2 20.6 22 23.4 24.8 26.2 27.6 29 30.4 31.8 33.2 34.6 36 37.4 38.8 40.2 41.6 43 44.4 45.8 47.2 48.6 50];
            app.ShortCutwindowSlider.Value = 1;
            app.ShortCutwindowLabel = uilabel(app.SettingTab);
            app.ShortCutwindowLabel.Text = {'ShortCut'; 'window'};
            app.diffCheckBox = uicheckbox(app.SettingTab);
            app.diffCheckBox.Text = 'diff';
            app.Plotflag1enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag1enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag1enableCheckBoxValueChanged, true);
            app.Plotflag1enableCheckBox.Text = 'enable';
            app.PlotflagLabel = uilabel(app.SettingTab);
            app.PlotflagLabel.Text = 'Plot flag1';
            app.Plotflag1DropDown = uidropdown(app.SettingTab);
            app.Plotflag1DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag1DropDownValueChanged, true);
            app.Plotflag2enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag2enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag2enableCheckBoxValueChanged, true);
            app.Plotflag2enableCheckBox.Text = 'enable';
            app.Plotflag2DropDownLabel = uilabel(app.SettingTab);
            app.Plotflag2DropDownLabel.Text = 'Plot flag2';
            app.Plotflag2DropDown = uidropdown(app.SettingTab);
            app.Plotflag2DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag2DropDownValueChanged, true);
            app.Plotflag3enableCheckBox = uicheckbox(app.SettingTab);
            app.Plotflag3enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @Plotflag3enableCheckBoxValueChanged, true);
            app.Plotflag3enableCheckBox.Text = 'enable';
            app.Plotflag3DropDownLabel = uilabel(app.SettingTab);
            app.Plotflag3DropDownLabel.Text = 'Plot flag3';
            app.Plotflag3DropDown = uidropdown(app.SettingTab);
            app.Plotflag3DropDown.ValueChangedFcn = createCallbackFcn(app, @Plotflag3DropDownValueChanged, true);
            app.Button_Rotate = uibutton(app.UIFigure, 'push');
            app.Button_Rotate.ButtonPushedFcn = createCallbackFcn(app, @Button_RotatePushed, true);
            app.Button_Rotate.Icon = fullfile(pathToMLAPP, 'rotate.jpg');
            app.Button_Rotate.Text = '';
            app.Button_Expand = uibutton(app.UIFigure, 'push');
            app.Button_Expand.ButtonPushedFcn = createCallbackFcn(app, @Button_ExpandPushed, true);
            app.Button_Expand.Icon = fullfile(pathToMLAPP, 'expand.jpg');
            app.Button_Expand.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end